classdef Modular_CDPR_TK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        endtimeEditFieldLabel           matlab.ui.control.Label
        endtimeEditField                matlab.ui.control.NumericEditField
        timestepEditFieldLabel          matlab.ui.control.Label
        timestepEditField               matlab.ui.control.NumericEditField
        generateButton                  matlab.ui.control.Button
        TrajectoryInfoTextAreaLabel     matlab.ui.control.Label
        TrajectoryInfoTextArea          matlab.ui.control.TextArea
        starttimeEditFieldLabel         matlab.ui.control.Label
        starttimeEditField              matlab.ui.control.NumericEditField
        startposeandpositionLabel       matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditField_3Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        mmLabel                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        endposeandpositionLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        bEditField_2Label               matlab.ui.control.Label
        bEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        aEditField_2Label               matlab.ui.control.Label
        aEditField_2                    matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        WarningTextAreaLabel            matlab.ui.control.Label
        WarningTextArea                 matlab.ui.control.TextArea
        MaxaEditFieldLabel              matlab.ui.control.Label
        MaxaEditField                   matlab.ui.control.NumericEditField
        ms2Label                        matlab.ui.control.Label
        ModeDropDownLabel               matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        MaxvEditFieldLabel              matlab.ui.control.Label
        MaxvEditField                   matlab.ui.control.NumericEditField
        ResetPositionButton             matlab.ui.control.Button
        showobstaclesgeneratedCheckBox  matlab.ui.control.CheckBox
        updatestartposeButton           matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        azimuthSliderLabel              matlab.ui.control.Label
        azimuthSlider                   matlab.ui.control.Slider
        elevationSliderLabel            matlab.ui.control.Label
        elevationSlider                 matlab.ui.control.Slider
        ResetViewButton                 matlab.ui.control.Button
        AdjustViewOf3DGraphLabel        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        MaxTensionEditFieldLabel        matlab.ui.control.Label
        MaxTensionEditField             matlab.ui.control.NumericEditField
        MinTensionEditFieldLabel        matlab.ui.control.Label
        MinTensionEditField             matlab.ui.control.NumericEditField
        WrenchSettingLabel              matlab.ui.control.Label
        NLabel_2                        matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        obstacleavoidancesimulationPanel  matlab.ui.container.Panel
        GenerateTuneobstaclesLabel      matlab.ui.control.Label
        lengthmmEditFieldLabel          matlab.ui.control.Label
        lengthmmEditField               matlab.ui.control.NumericEditField
        widthmmEditFieldLabel           matlab.ui.control.Label
        widthmmEditField                matlab.ui.control.NumericEditField
        heightmmEditFieldLabel          matlab.ui.control.Label
        heightmmEditField               matlab.ui.control.NumericEditField
        GenerateObstacleTrajectoryButton  matlab.ui.control.Button
        clearobstaclesButton            matlab.ui.control.Button
        PlanningConfigurationsLabel     matlab.ui.control.Label
        ModeDropDown_2Label             matlab.ui.control.Label
        ModeDropDown_2                  matlab.ui.control.DropDown
        OptimizationFreqLabel           matlab.ui.control.Label
        OptimizationFreqEditField       matlab.ui.control.NumericEditField
        maxanchorstepLabel              matlab.ui.control.Label
        maxanchorstepEditField          matlab.ui.control.NumericEditField
        maxanchorspeedLabel             matlab.ui.control.Label
        maxanchorspeedEditField         matlab.ui.control.NumericEditField
        specifypathforendeffectorButton  matlab.ui.control.Button
        mLabel                          matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        generatesimulationresultButton  matlab.ui.control.Button
        saveobstaclesButton             matlab.ui.control.Button
        loadobstaclesButton             matlab.ui.control.Button
        saveresultButton                matlab.ui.control.Button
        loadresultButton                matlab.ui.control.Button
        resultnameEditField             matlab.ui.control.EditField
        resultnameEditField_2           matlab.ui.control.EditField
        resultnameEditField_3           matlab.ui.control.EditField
        resultnameEditField_4           matlab.ui.control.EditField
        playtheresultButton             matlab.ui.control.Button
        saveorloadobstaclesLabel        matlab.ui.control.Label
        managesimulationresultsLabel    matlab.ui.control.Label
        PlotForceDistributionGraphButton  matlab.ui.control.Button
        SpeedOfObstacleEditFieldLabel   matlab.ui.control.Label
        SpeedOfObstacleEditField        matlab.ui.control.NumericEditField
        msLabel_3                       matlab.ui.control.Label
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        PlotEEStiffnessGraphButton      matlab.ui.control.Button
        ms2Label_3                      matlab.ui.control.Label
        AccelerationOfObstacleEditFieldLabel  matlab.ui.control.Label
        AccelerationOfObstacleEditField  matlab.ui.control.NumericEditField
        ObstacleIDDropDownLabel         matlab.ui.control.Label
        ObstacleIDDropDown              matlab.ui.control.DropDown
        YmmSpinnerLabel                 matlab.ui.control.Label
        YmmSpinner                      matlab.ui.control.Spinner
        ZmmSpinnerLabel                 matlab.ui.control.Label
        ZmmSpinner                      matlab.ui.control.Spinner
        RuntimeOfObstacleEditFieldLabel  matlab.ui.control.Label
        RuntimeOfObstacleEditField      matlab.ui.control.NumericEditField
        sLabel_4                        matlab.ui.control.Label
        XmmSpinnerLabel                 matlab.ui.control.Label
        XmmSpinner                      matlab.ui.control.Spinner
        DirectionAngle1SpinnerLabel     matlab.ui.control.Label
        DirectionAngle1Spinner          matlab.ui.control.Spinner
        DirectionAngle2SpinnerLabel     matlab.ui.control.Label
        DirectionAngle2Spinner          matlab.ui.control.Spinner
        AddButton                       matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        minDisToCableButton             matlab.ui.control.Button
        sLabel_5                        matlab.ui.control.Label
        PlanningtimestepLabel           matlab.ui.control.Label
        PlanningtimestepEditField       matlab.ui.control.NumericEditField
        PlotAnchorTrajectoryGraphButton  matlab.ui.control.Button
        CANChannelCDPRopenloopcontrolPanel  matlab.ui.container.Panel
        ConnectandStartCANLabel         matlab.ui.control.Label
        ConnectCANButton                matlab.ui.control.Button
        StartCANButton                  matlab.ui.control.Button
        ResetCANButton                  matlab.ui.control.Button
        CANIndexDropDownLabel           matlab.ui.control.Label
        CANIndexDropDown                matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        BaudRateDropDown                matlab.ui.control.DropDown
        KbpsLabel                       matlab.ui.control.Label
        SingleMotorTestspeedpositionLabel  matlab.ui.control.Label
        rLabel                          matlab.ui.control.Label
        rpmLabel                        matlab.ui.control.Label
        TestButton                      matlab.ui.control.Button
        IndexEditFieldLabel             matlab.ui.control.Label
        IndexEditField                  matlab.ui.control.NumericEditField
        RoundEditFieldLabel             matlab.ui.control.Label
        RoundEditField                  matlab.ui.control.NumericEditField
        SpeedEditFieldLabel             matlab.ui.control.Label
        SpeedEditField                  matlab.ui.control.NumericEditField
        ResetDriverButton               matlab.ui.control.Button
        SetModeDropDownLabel            matlab.ui.control.Label
        SetModeDropDown                 matlab.ui.control.DropDown
        SetModeButton                   matlab.ui.control.Button
        MovableAnchorTest5orderlinearplanningLabel  matlab.ui.control.Label
        AnchorIDEditFieldLabel          matlab.ui.control.Label
        AnchorIDEditField               matlab.ui.control.NumericEditField
        SetDisplacementEditFieldLabel   matlab.ui.control.Label
        SetDisplacementEditField        matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        MaxSpeedEditFieldLabel          matlab.ui.control.Label
        MaxSpeedEditField               matlab.ui.control.NumericEditField
        msLabel_2                       matlab.ui.control.Label
        TestButton_2                    matlab.ui.control.Button
        ms2Label_2                      matlab.ui.control.Label
        MaxAccelEditFieldLabel          matlab.ui.control.Label
        MaxAccelEditField               matlab.ui.control.NumericEditField
        SimulationResultTestLabel       matlab.ui.control.Label
        GoToStartPosespecifiedButton    matlab.ui.control.Button
        StartTestingSimulationResultButton  matlab.ui.control.Button
        GoBackToOriginalPoseButton      matlab.ui.control.Button
        ResetModuleButton               matlab.ui.control.Button
        MotorTypeDropDownLabel          matlab.ui.control.Label
        MotorTypeDropDown               matlab.ui.control.DropDown
        ResetInitAllModulesButton       matlab.ui.control.Button
        ResetPositionButton_2           matlab.ui.control.Button
        UR5ARMControlForobstacleavoidancetestPanel  matlab.ui.container.Panel
        URIPAdressEditFieldLabel        matlab.ui.control.Label
        URIPAdressEditField             matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        PortEditField                   matlab.ui.control.NumericEditField
        ConnectUR5Button                matlab.ui.control.Button
        TestObstacleTrajectoryButton    matlab.ui.control.Button
        ResetObstaclePositionButton     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % global variables
    properties (Access = private)
      % basic mechanical parameters and modular class
        MovAnchor CdprMovAnchor % movable anchor class declaration
        Platform CdprPlatform % Platform class declaration 
        Cable CdprCable % cable class declaration
        
        R_encoderPully = 13.35*1e-3; % radius of the pully connected with Omenron encoder, unit:m
        R_anchorPully = 8*1e-3; % radius of the pully for bringing out cables, unit：m
        S_leadScrew = 10*1e-3; % lead of the lead screw
        R_winch = 18*1e-3; % radius of the winches fixed on motors, unit:m
        
        A_T = zeros(6, 8); % 6X8, structure matrix(G-coordinate)
        
      % Topology information class
        vertex Vertex % 1X8 Vertex class array
        edge Edge % 1X8 Edge class array
        face Face % 1X8 Face class array
        
      % linear trajectory planning parameters
        startPose = [0 0 37 0 0 0]'*1e-3; % position and pose of start point
        endPose = [0 0 37 0 0 0]'*1e-3; % position and pose of end point
        startTime = 0;
        endTime = 5;
        timeStep = 0.1;
        trajInfo CdprTrajInfo % trajectory infomation class declaration
        trajPlanMode = 0; % select trajectory planning algorithm, mode1:0, specify time; mode2:1, specify max speed and acceleration
        
        % obstacle avoidance
        obsBB ObsBB
        obsNumber = 0; %number of obstacles generated
        default_dmin = 10*1e-3; %默认生成障碍物时最小允许距离的赋值
        default_ds = 30*1e-3; %默认生成障碍物时安全检测距离的赋值
        
        % CAN communication(USB-CAN)
        CAN CdprCAN %USB-CAN handle class for communication with motors
        
        % MaxonRE35 Motors
        winchMotors Motor %motor handle class for winch motors
        railMotors Motor %motor handle class for rail motors
        
        % UR5 Interface
        UR5 UR5Interface %ur5 interface, use it to control obstacle trajectory
        linkOffset_Calibrate = 510*1e-3; % m, 标定时的偏移距离（标定用的杆长）
        linkOffset_ObsBB = 325*1e-3; % m, 固定障碍物的上表面偏移距离（连接障碍物上表面的杆长）
        TCP_CaliPose_RPY = [-502.13*1e-3, -521.11*1e-3, -195.76*1e-3, pi, 0, 2.339]'; %6X1, m, rad（RPY）,标定UR5机械臂时得到的TCP坐标
        TCP_CaliPose_RotVec = [-502.13*1e-3, -521.11*1e-3, -195.76*1e-3, -1.2203, -2.8906, -0.0390]'; %6X1, m, rad（rotational vector）,标定UR5机械臂时得到的TCP坐标
        T_G2UR5; %transform matrix from G-coordinate to UR5-Coordinate
    end 
    
    methods (Access = private)
        % function: init CDPR parameters
        function CDPR_ParaInit(app)
            % init movable anchor structure
            app.MovAnchor=CdprMovAnchor();
          
            %init Platform structure
            app.Platform=CdprPlatform();
            
            %init cable structure
            ccDis_min = 0.02; %unit:m
            ccDis_Intersect = 0.05; %unit:m
            cpDis_min = sind(20); %正弦值(0~1)
            app.Cable=CdprCable(ccDis_min, ccDis_Intersect, cpDis_min);
            app.calculateCable(); %calculate current cable length between anchors
            app.Cable.OriginLength = app.Cable.Length;
            
            %init trajInfo structure
            maxVelBound_Platfrom = app.MaxvEditField.Value; %m/s
            maxAccelBound_Platfrom = app.MaxaEditField.Value; %m/(s^2)
            maxVelBound_Anchor = app.maxanchorspeedEditField.Value; %m/s
            maxStepBound_Anchor = app.maxanchorstepEditField.Value; %m
            optiFreq = app.OptimizationFreqEditField.Value;
            app.trajInfo = CdprTrajInfo(maxVelBound_Platfrom, maxAccelBound_Platfrom, ...
                maxVelBound_Anchor, maxStepBound_Anchor, optiFreq, app.timeStep);
            
             %% init wrench and tension settings
            Wrench = [0; 0; -50; 0; 0; 0]; % 6X1, external force and torque，N, N*m
            feaspTension = [10,190]; % 8X2, feasible range of tension forces in 8 cables
            app.trajInfo.wrenchSetting = WrenchSetting(Wrench, feaspTension);
            app.trajInfo.wrenchSetting = app.trajInfo.wrenchSetting.createRangeCube(); %create force range defined 8-D hyper-cube
            app.UITable.Data = Wrench';
            
            %init topology information
            f=load("face.mat");
            e=load("edge.mat");
            v=load("vertex.mat");
            for i=1:size(v.vertex, 2)
                app.vertex(i) = Vertex(v.vertex(i));
            end
            
            for i=1:size(e.edge, 2)
                app.edge(i) = Edge(e.edge(i));
            end
            
            for i=1:size(f.face, 2)
                app.face(i) = Face(f.face(i));
            end
            
            %init obstacle related objects
            app.obsBB=ObsBB();
            
            %init USB-CAN class
            app.CAN = CdprCAN();
            
            %init winch Motors and rail Motors class
            for i=1:8
                app.winchMotors(i) = Motor(0, i, app.CAN);
                app.railMotors(i) = Motor(1, i, app.CAN);
            end
            
            %init UR5 Interface
            app.UR5 = UR5Interface("192.168.1.10");
            offsetPose = [0, 0, app.linkOffset_Calibrate, pi, 0, 0]'; % compute offset transformation matrix(from G to TCP)
            app.T_G2UR5 = Txyz(app.TCP_CaliPose_RPY) * Txyz(offsetPose); % compute transformation matrix(from G to UR5)
        end
        
        % function: update Platform anchors' G-coordinate 
        function updatePlatform(app)
            T_GP = Tzyx(app.Platform.pose_G);
            temPosition_P = cat(1, app.Platform.anchorPosition_P, ones(1,8));
            temPosition_G = T_GP * temPosition_P;
            app.Platform.anchorPosition_G = temPosition_G(1:3,:);
        end
        
        % function: calculate cable parameters
        function calculateCable(app)
             %calculate cable length and vector,pully rotation angle
            for i=1:8
                [app.Cable.Length(i), app.Cable.UniVector(:,i), app.Cable.TanLinVector(:,i), ...
                    app.MovAnchor.PulCenter(:,i), app.MovAnchor.PulRotz_L(i)] ...
                 = CDPRInvKine(app.Platform.pose_G, app.Platform.anchorPosition_P(:,i), ...
                    app.MovAnchor.positon_G(:,i), app.R_anchorPully, app.MovAnchor.AnRotz_G(i));
                Rot_PG = Rzyx(app.Platform.pose_G(4:6))';
                app.Cable.UniVector_P(:,i) = Rot_PG * app.Cable.UniVector(:,i);
                app.Cable.TanLinVector_P(:,i) = app.Cable.UniVector_P(:,i) * app.Cable.Length(i);
            end
        end
        
        % function: draw and display CDPR 3D gragh
        function drawCDPR(app)
            %draw CDPR frame
            cla(app.UIAxes,'reset');
            grid(app.UIAxes,"on");
            xlabel(app.UIAxes, "X (m)","FontSize",14); 
            ylabel(app.UIAxes, "Y (m)","FontSize",14);
            zlabel(app.UIAxes, "Z (m)","FontSize",14);
            title(app.UIAxes, "CDPR 3D Simulation","FontSize",18);
            for i = 1:8     
                X = [app.MovAnchor.caliPosition_G(1,i),app.MovAnchor.endPosition_G(1,i)];
                Y = [app.MovAnchor.caliPosition_G(2,i),app.MovAnchor.endPosition_G(2,i)];
                Z = [app.MovAnchor.caliPosition_G(3,i),app.MovAnchor.endPosition_G(3,i)];
                plot3(app.UIAxes, X, Y, Z, '-k','LineWidth',3);
                hold(app.UIAxes,"on");
            end
            
            %draw platform
            X = zeros(4,2);
            Y = zeros(4,2);
            Z = zeros(4,2);
            for i=1:8
                if i<=4
                    X(i,1) = app.Platform.anchorPosition_G(1,i);
                    Y(i,1) = app.Platform.anchorPosition_G(2,i);
                    Z(i,1) = app.Platform.anchorPosition_G(3,i);
                else
                    X(i-4,2) = app.Platform.anchorPosition_G(1,i);
                    Y(i-4,2) = app.Platform.anchorPosition_G(2,i);
                    Z(i-4,2) = app.Platform.anchorPosition_G(3,i);
                end
            end
            scatter3(app.UIAxes, X(:,1), Y(:,1), Z(:,1), 30, "black", "filled");
            scatter3(app.UIAxes, X(:,2), Y(:,2), Z(:,2), 30, "black", "filled");
            fill3(app.UIAxes, X, Y, Z, 'b','LineWidth',1); %draw platform polygons
            
            C1 = 0.5 * (app.Platform.anchorPosition_G(:,1) + app.Platform.anchorPosition_G(:,3)); 
            C2 = 0.5 * (app.Platform.anchorPosition_G(:,5) + app.Platform.anchorPosition_G(:,7));
            X = [C1(1) C2(1)]; Y = [C1(2) C2(2)]; Z = [C1(3) C2(3)];    
            plot3(app.UIAxes, X,Y,Z,'-b','LineWidth',3); hold(app.UIAxes,"on");
            
            %draw pully
            RotZ = @(a) [cos(a) -sin(a) 0;
                        sin(a)  cos(a) 0;
                         0          0  1];
            for i=1:8
                n_Ai = [1 0 0]'; %Ai系中滑轮面法向量
                rot = app.MovAnchor.AnRotz_G(i) + app.MovAnchor.PulRotz_L(i);
                n = RotZ(rot) * n_Ai;
                plotCircle3D(app.UIAxes, app.MovAnchor.PulCenter(:,i), n', app.R_anchorPully);
            end
            
            %draw cable
            for i=1:8
                Mi = app.Cable.TanLinVector(:,i) + app.Platform.anchorPosition_G(:,i);
                Bi = app.Platform.anchorPosition_G(:,i);
                X = [Bi(1) Mi(1)]; Y = [Bi(2) Mi(2)]; Z = [Bi(3) Mi(3)]; 
                plot3(app.UIAxes, X,Y,Z,'-r','LineWidth',1); 
            end
            %set view orientation
            view(app.UIAxes, app.azimuthSlider.Value, app.elevationSlider.Value);
        end
        
        function updateVoronoi(app)
            %说明：计算各绳索向量所在的voronoi region，并计算离区域元素的夹角，返回包含相应区域类型和索引的元胞数组、夹角数组
            %输入：绳索单位向量，点、面的拓扑信息表
            %输出：绳索向量与对应元素正弦值的平方，以及包含相应区域类型和索引的元胞数组，第一行为区域类型，第二行为对应的索引
            univector = app.Cable.UniVector_P;
            for i=1:size(univector, 2)
                findFlag = 0; %找到区域标志
                judgeVertex2 =0 ; %判断点域的判据2：满足判据1的次数
                numEdge =  size(app.vertex(i).localEdgeVector, 2);
                for j=1:numEdge
                    %计算点域判据
                    judgeVertex1 = dot(app.vertex(i).localEdgeVector(:,j), univector(:,i)); %判断点域的判据1
                    if  judgeVertex1>0 %不在点域
                        %记录索引
                        nFaceId_M = app.vertex(i).n_facesId(j); %middle, nij 对应面的ID
                        if j==1
                            nFaceId_N = app.vertex(i).n_facesId(numEdge); %negative, nij-1 对应面的ID
                        else
                            nFaceId_N = app.vertex(i).n_facesId(j-1); 
                        end
                        if j== numEdge
                            nEdge_P = 1; %positive, eij+1 对应的ID
                        else
                            nEdge_P = j+1;
                        end
                        %计算边判据
                        judgeEdge1 = dot(univector(:,i), cross(app.vertex(i).localEdgeVector(:,j), app.face(nFaceId_M).normal_vec)); %边判据1
                        judgeEdge2 = dot(univector(:,i), cross(app.vertex(i).localEdgeVector(:,j), app.face(nFaceId_N).normal_vec)); %边判据2
                        %判断是否为边域
                        if  judgeEdge1>=0 &&  judgeEdge2<=0%在边域
                            app.Cable.voronoi{1, i} = 'e'; 
                            app.Cable.voronoi{2, i} = app.vertex(i).n_edgesId(j);
                            app.Cable.cpDistance(i) = 1-judgeVertex1*judgeVertex1; %与边夹角的正弦值的平方
                            findFlag =1;
                            break;
                        else
                            %计算面判据
                            judgeFace1 = dot(univector(:,i), app.face(nFaceId_M).normal_vec); %面判据1
                            judgeFace2 = judgeEdge1; %面判据2 
                            judgeFace3 = dot(univector(:,i), cross(app.vertex(i).localEdgeVector(:, nEdge_P), app.face(nFaceId_M).normal_vec));
                            %判断是否为面域
                            if judgeFace1>0 && judgeFace2<0 && judgeFace3>0
                            app.Cable.voronoi{1, i} = 'f';
                            app.Cable.voronoi{2, i} = app.vertex(i).n_facesId(j);
                            app.Cable.cpDistance(i) = judgeFace1*judgeFace1; %与面夹角的正弦值的平方（=与面法向量夹角的余弦值平方）
                            findFlag =1;
                            break;
                            end
                        end
                    else %可能在点域
                        judgeVertex2 = judgeVertex2 +1; %满足在点域的条件次数加1
                    end
                end
                if ~findFlag 
                    if  judgeVertex2==numEdge  %在点域
                    app.Cable.voronoi{1, i} = 'v';
                    app.Cable.voronoi{2, i} = i;
                    app.Cable.cpDistance(i) = 1; %安全区设置正弦值为1，夹角90°，不干涉
                    else  %还没找到，说明干涉
                    app.Cable.voronoi{1, i} = 'C'; %碰撞
                    app.Cable.voronoi{2, i} = 0;
                    app.Cable.cpDistance(i) = 0; %碰撞设置正弦值为0，夹角0°，干涉
                    end
                end
            end
        end
        
        function updateCableDis(app)
            %说明：计算各绳索之间的距离平方值
            for i=1:8
                for j=(i+1):8
                    index = j-i+((8-1)+(8-i+1))*(i-1)/2; %索引
                    l_ij = app.Platform.anchorDis(index); 
                    uniVec_ij = app.Platform.anchorUniVec_P(:,index);
                    uniVec_ji = -uniVec_ij;
                    % region判断(一级)
                    % i，j均在点域？
                    cond11 = (app.Cable.voronoi{1,i}=='v'); 
                    cond21 = (app.Cable.voronoi{1,j}=='v');
                    if cond11 && cond21
                        app.Cable.ccDistance(index) = l_ij*l_ij;
                        continue;
                    end
                    % i在点域且j为i的邻点且u_j在i不相邻面域或边域
                    % 或j在点域且j为i的邻点且u_i在j不相邻面域或边域
                    cond12 = ismember(j, app.vertex(i).n_verticesId); cond22 = cond12;
                    cond13 = (app.Cable.voronoi{1,j}=='e' && ~ismember(app.Cable.voronoi{2,j}, app.vertex(i).n_edgesId))...
                            || (app.Cable.voronoi{1,j}=='f' && ~ismember(app.Cable.voronoi{2,j}, app.vertex(i).n_facesId));
                    cond23 = (app.Cable.voronoi{1,i}=='e' && ~ismember(app.Cable.voronoi{2,i}, app.vertex(j).n_edgesId))...
                            || (app.Cable.voronoi{1,i}=='f' && ~ismember(app.Cable.voronoi{2,i}, app.vertex(j).n_facesId));
                    if (cond11 && cond12 && cond13) || (cond21 && cond22 && cond23)
                        if cond11 % i在点域
                            a = dot(uniVec_ji, app.Cable.UniVector_P(:, j));
                        else % j在点域
                            a = dot(uniVec_ij, app.Cable.UniVector_P(:, i));
                        end
                        if a>=0
                            app.Cable.ccDistance(index) = l_ij*l_ij*(1-a*a);
                            continue;
                        else
                            app.Cable.ccDistance(index) = l_ij*l_ij;
                            continue;
                        end
                    end
                    
                    % 方位判断(二级)
                    a1 = dot(app.Cable.UniVector_P(:, i), uniVec_ij);
                    a2 = dot(app.Cable.UniVector_P(:, j), uniVec_ji);
                    uniVec_n1 = app.Cable.UniVector_P(:, i) - a1*uniVec_ij;
                    uniVec_n2 = app.Cable.UniVector_P(:, j) - a2*uniVec_ji;
                    cond3 = dot(uniVec_n1, uniVec_n2);
                    if a1<=0 && a2<=0
                        app.Cable.ccDistance(index) = l_ij*l_ij; continue;
                    elseif (a1<=0 && abs(a1)>=abs(a2)) || (cond3<=0 && a2>=a1)
                        app.Cable.ccDistance(index) = l_ij*l_ij*dot(uniVec_n2, uniVec_n2); continue;
                    elseif (a2<=0 && abs(a2)>=abs(a1)) || (cond3<=0 && a1>a2)
                        app.Cable.ccDistance(index) = l_ij*l_ij*dot(uniVec_n1, uniVec_n1); continue;
                    end
                    
                    % 调用CGAL库计算最短距离平方
                    cable_i = [app.Cable.TanLinVector(:, i) + app.Platform.anchorPosition_G(:, i), app.Platform.anchorPosition_G(:, i)];
                    cable_j = [app.Cable.TanLinVector(:, j) + app.Platform.anchorPosition_G(:, j), app.Platform.anchorPosition_G(:, j)];
                    app.Cable.ccDistance(index) = calCableSqrDis_CGAL(cable_i, cable_j);
                end
            end
        end
        
        function newObsBB=randObsBB(app, dmin, ds)
            %说明：随机生成障碍物的AABB
            %根据自定义的长宽高以及动平台移动的坐标范围随机生成障碍物的AABB，且参数均为随机整数
            length = randi(app.lengthRange)*1e-3; %单位化为m
            width = randi(app.widthRange)*1e-3;
            height = randi(app.heightRange)*1e-3;
            xRange = [(app.Platform.boundary(1,1)+length/2) (app.Platform.boundary(1,2)-length/2)];
            yRange = [(app.Platform.boundary(2,1)+width/2) (app.Platform.boundary(2,2)-width/2)];
            zRange = [(app.Platform.boundary(3,1)+height/2) (app.Platform.boundary(3,2)-height/2)];
            origin = [randi(round(xRange*1e3)); randi(round(yRange*1e3)); randi(round(zRange*1e3))]*1e-3;
            if nargin==3 %输入参数完整
                newObsBB=ObsBB(length, width, height, origin, 0, 0, dmin, ds); %创建障碍物bounding box类
            else
                newObsBB=ObsBB(length, width, height, origin, 0, 0, app.default_dmin, app.default_ds); %创建障碍物bounding box类
            end
        end
        
        function updateCableObsDis(app)
            %说明：计算绳索与障碍物的距离（实际计算值为距离平方）
            numbObs = app.obsNumber;
            numCable = 8;
            app.Cable.coDistance = zeros(numbObs, numCable);
            for i=1:numCable
                for j=1:numbObs
                    vecObs = app.obsBB(j).origin - app.Platform.anchorPosition_G(:, i);
                    a = dot(vecObs, app.Cable.UniVector(:, i));
                    vecObs_t = a * app.Cable.UniVector(:, i);
                    vecObs_n = vecObs - vecObs_t;
                    dis = dot(vecObs, vecObs);
                    if dis>=(app.obsBB(j).Rs)^2 %未进入检测球
                        if a<=0 %绳索与到检测球球心向量成钝角
                            app.Cable.coDistance(j, i) = dis; %距离平方
                            continue; %进入下一轮j
                        end
                        
                        dis = dot(vecObs_n, vecObs_n);
                        
                        if dis>=(app.obsBB(j).Rs)^2 %绳索未进入检测球
                            app.Cable.coDistance(j, i) = dis; %距离平方
                            continue; %进入下一轮j
                        end 
                    else %进入检测球，调用openGJK算
                        cable = [app.Cable.TanLinVector(:, i) + app.Platform.anchorPosition_G(:, i), app.Platform.anchorPosition_G(:, i)];
                        app.Cable.coDistance(j, i) = openGJK(cable, app.obsBB(j).vertices);
                        app.Cable.coDistance(j, i) = app.Cable.coDistance(j, i) * app.Cable.coDistance(j, i); %距离平方
                    end
                end
            end
        end
        
        function updatePlatObsDis(app)
            %说明：用openGJK计算动平台与障碍物的距离
            numbObs = app.obsNumber;
            if numbObs>=1
                for i=1:numbObs
                    app.Platform.poDistance(i) = openGJK(app.Platform.anchorPosition_G, app.obsBB(i).vertices);
                end
            end
        end
        
        function platLinearTrajPlan(app)
            % 5-order trajectory planning
            if app.trajPlanMode == 0
                [app.trajInfo.platformTraj.pos, app.trajInfo.platformTraj.vel, app.trajInfo.platformTraj.accel, ...
                app.trajInfo.platformTraj.eular, app.trajInfo.time, app.trajInfo.Number]...
                = trajPlan1(app.startPose, app.endPose, app.startTime, app.endTime, app.timeStep); 
            elseif app.trajPlanMode == 1
                [app.trajInfo.platformTraj.pos, app.trajInfo.platformTraj.vel, app.trajInfo.platformTraj.accel, ...
                app.trajInfo.platformTraj.eular, app.trajInfo.time, app.trajInfo.Number]...
                = trajPlan2(app.startPose, app.endPose, app.trajInfo.platformTraj.maxVelBound, app.trajInfo.platformTraj.maxAccelBound, app.startTime, app.timeStep);
            end
            
            app.trajInfo.platformTraj.magVel = zeros(1,app.trajInfo.Number);
            app.trajInfo.platformTraj.magAccel = zeros(1,app.trajInfo.Number);
            for i=1:app.trajInfo.Number
                app.trajInfo.platformTraj.magVel(i) = norm(app.trajInfo.platformTraj.vel(:,i)); % magnitude of velocity
                app.trajInfo.platformTraj.magAccel(i) = norm(app.trajInfo.platformTraj.accel(:,i)); % magnitude of acceleration
            end
                app.trajInfo.platformTraj.maxVel = max(app.trajInfo.platformTraj.magVel); % maximum velocity
                app.trajInfo.platformTraj.maxAccel = max(app.trajInfo.platformTraj.magAccel); % maximum acceleration
                
            % Info display
            s1 = sprintf("Maximum speed:\n\t%0.3f m/s\n\n",app.trajInfo.platformTraj.maxVel);
            s2 = sprintf("Maximum acceleration:\n\t%0.3f m/(s^2)\n\n",app.trajInfo.platformTraj.maxAccel);
            if app.trajPlanMode == 1
                temps1 = sprintf("Maximum speed allowed:\n\t%0.3f m/s\n\n",app.trajInfo.platformTraj.maxVelBound);
                temps2 = sprintf("Maximum acceleration allowed:\n\t%0.3f m/(s^2)\n\n",app.trajInfo.platformTraj.maxAccelBound);          
                s2 = strcat(s2, temps1, temps2);
            end
            s3 = sprintf("Total Time:%0.1f s\n\n",(app.trajInfo.time(end)-app.trajInfo.time(1)));
            app.TrajectoryInfoTextArea.Value = strcat(s1, s2, s3);
            
            app.trajInfo.anchorTraj.pos = zeros(8,app.trajInfo.Number); %预分配内存
            app.trajInfo.cableTraj.cableTension = zeros(8,app.trajInfo.Number); %预分配内存
            app.trajInfo.cableTraj.cableLength = zeros(8,app.trajInfo.Number); %预分配内存
            app.trajInfo.winchMotorsPos = zeros(8,app.trajInfo.Number); %预分配内存
            app.trajInfo.winchMotorsSpeed = zeros(8,app.trajInfo.Number); %预分配内存
            app.trajInfo.railMotorsPos = zeros(8,app.trajInfo.Number); %预分配内存
            app.trajInfo.railMotorsSpeed = zeros(8,app.trajInfo.Number); %预分配内存
            app.trajInfo.Stiffness = zeros(6,app.trajInfo.Number);%预分配内存
        end

        
        function displayObsBB(app)
            for i=1:app.obsNumber
                app.obsBB(i).fill3(app.UIAxes, 'y');
                app.obsBB(i).showVelDir(app.UIAxes, '-c');
                view(app.UIAxes, app.azimuthSlider.Value, app.elevationSlider.Value);
            end
        end
        
        function anchorTrajPlan_M1(app)
            %已知末端轨迹下用优化算法对动锚点座的避障轨迹规划，优化变量为动锚点步长+绳索拉力
            if app.ModeDropDown_2.Value == '0' % 在末端轨迹确定好的模式下
                opCount = 0;
    %             app.trajInfo.anchorTraj.pos = zeros(8,app.trajInfo.Number); %预分配内存
    %             app.trajInfo.cableTraj.cableTension = zeros(8,app.trajInfo.Number); %预分配内存
    %             app.trajInfo.cableTraj.cableLength = zeros(8,app.trajInfo.Number); %预分配内存
                app.MovAnchor = app.MovAnchor.setPosL(app.trajInfo.anchorTraj.pos(:,1)); %positon_L归零
                anchorTrajId(1) = 1;
                app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,1); app.trajInfo.platformTraj.eular(:,1)]); %更新末端位姿
                app.calculateCable(); %更新绳索参数
                
                PlatAnchorPosition_Former = app.Platform.anchorPosition_G; %记录初始动平台锚点位置
                Cable_Former = app.Cable; %记录初始绳索计算值
                
                updateStructureMat(app); %更新结构矩阵
                [app.Cable.tensionDistrib, ~] = forceDistrib1_Improved(app.A_T, app.trajInfo.wrenchSetting); %计算初始点张力分布
                app.trajInfo.cableTraj.cableTension(:,1) = app.Cable.tensionDistrib; %记录初始点张力分布
                
                %设置非线性优化函数
                option=optimoptions('fmincon',"Algorithm","interior-point","ConstraintTolerance",1e-8, "MaxFunctionEvaluations", 5000);          
                
                for i=2:app.trajInfo.Number  %从第二个点开始
                    opCount = opCount + 1; %统计点数
                    if opCount == app.trajInfo.anchorTraj.optiFreq %达到优化频率决定的点数
                        opCount = 0; %统计点数归零
                        app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,i); app.trajInfo.platformTraj.eular(:,i)]); %更新末端位姿
                        anchorTrajId(end + 1) = i; %记录采样点的索引
                        
                        %更新障碍物在轨迹上的位置
                        for n=1:app.obsNumber 
                            if i<=size(app.trajInfo.obsBBTraj{n}, 2) && size(app.trajInfo.obsBBTraj, 2)>0
                                obsTraj = app.trajInfo.obsBBTraj{n};
                                app.obsBB(n) = app.obsBB(n).setOriginPosition(obsTraj(:,i));
                            end
                        end
                        
                        %执行优化算法（非线性带约束局部优化）
                        %非线性约束函数1
                        nonlcon = @(X) constrainFunction1(X, app.Platform, PlatAnchorPosition_Former, app.MovAnchor, Cable_Former, ...
                                             app.obsBB, app.obsNumber, app.vertex, app.face, app.R_anchorPully, app.trajInfo.wrenchSetting); %全部约束
    %                     nonlcon = @(X) constrainFunction1_simple(X, app.Platform, app.MovAnchor, Cable_Former, ...
    %                                                 app.obsBB, app.obsNumber, app.R_anchorPully, app.wrenchSetting); %简单约束
                        %优化目标函数
                        fun = @(X) targetFunction1(X); %力分布均匀
                           
                        %优化初值：步长0 + 上一点的张力
                        X0(1:8) = 0; X0(9:16) = app.trajInfo.cableTraj.cableTension(:,anchorTrajId(end - 1)); 
                        %解的上下限设置
                        lb = [repmat(-app.trajInfo.anchorTraj.maxStep, 8,1); repmat(app.trajInfo.wrenchSetting.feaspTensionRange(1),8,1)];
                        ub = [repmat(app.trajInfo.anchorTraj.maxStep, 8,1); repmat(app.trajInfo.wrenchSetting.feaspTensionRange(2),8,1)];
                        X = fmincon(fun,X0,[],[],[],[],lb,ub,nonlcon,option); %执行优化算法
                        
                        app.MovAnchor = app.MovAnchor.setPosL(app.MovAnchor.positon_L + X(1:8)); %根据得到的步长+上一点动锚点位置更新本点动锚点局部位置
                        
                        app.trajInfo.anchorTraj.pos(:,anchorTrajId(end)) = app.MovAnchor.positon_L; %记录本点动锚点位置
                        app.trajInfo.cableTraj.cableTension(:,anchorTrajId(end)) = X(9:16); %记录当前点优化得到的张力
                    
                        PlatAnchorPosition_Former = app.Platform.anchorPosition_G; %记录当前动平台锚点位置，供下一次优化计算使用
                        app.calculateCable(); %计算当前绳索参数
                        Cable_Former = app.Cable; %记录当前绳索参数，供下一次优化计算使用
                    end
                end
                
                %后处理
                if app.trajInfo.anchorTraj.optiFreq > 1 %当优化频率点数大于1时进行插值与补偿
                    timeSample = app.trajInfo.time(anchorTrajId);
                    anchorTrajSample = app.trajInfo.anchorTraj.pos(:, anchorTrajId);
                    app.trajInfo.anchorTraj.pos = makima(timeSample, anchorTrajSample, app.trajInfo.time); %对动锚点位置关于时间进行修正Akima分段三次插值
                end
                
                %计算并记录各个轨迹点绳索长度、拉力
                for i=1:app.trajInfo.Number
                    app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,i); app.trajInfo.platformTraj.eular(:,i)]); %更新末端位姿
                    app.MovAnchor = app.MovAnchor.setPosL(app.trajInfo.anchorTraj.pos(:, i)); %更新动锚点坐标
                    app.calculateCable(); %计算绳长、滑轮位姿
                    app.trajInfo.cableTraj.cableLength(:, i) = app.Cable.Length; %记录绳长
                    
                    %执行力分配算法，计算拉力分配
                    if ~ismember(i, anchorTrajId) %当前轨迹点索引不在优化采样点索引里时才补充力分配
                        updateStructureMat(app); %更新结构矩阵
                        [app.Cable.tensionDistrib, ~] = forceDistrib1_Improved(app.A_T, app.trajInfo.wrenchSetting); %计算张力分布
                        app.trajInfo.cableTraj.cableTension(:,i) = app.Cable.tensionDistrib; %记录张力分布
                    end
                end
                
                %计算更新并存储各电机轨迹
                app.updateMotorsTraj();
                
                %动画演示
                playTrajInfo(app);
            end
        end
        
        
        function playTrajInfo(app)
            if app.trajInfo.Number>0
                 % 3D simulation display and Info display
                s1 = sprintf("Maximum speed:\n\t%0.3f m/s\n\n",app.trajInfo.platformTraj.maxVel);
                s2 = sprintf("Maximum acceleration:\n\t%0.3f m/(s^2)\n\n",app.trajInfo.platformTraj.maxAccel);
                if app.trajPlanMode == 1
                    temps1 = sprintf("Maximum speed allowed:\n\t%0.3f m/s\n\n",app.trajInfo.platformTraj.maxVelBound);
                    temps2 = sprintf("Maximum acceleration allowed:\n\t%0.3f m/(s^2)\n\n",app.trajInfo.platformTraj.maxAccelBound);          
                    s2 = strcat(s2, temps1, temps2);
                end
                s3 = sprintf("Total Time:%0.1f s\n\n",(app.trajInfo.time(end)-app.trajInfo.time(1)));
            
                pauseTime = app.timeStep*1e-6;
                for i=1:app.trajInfo.Number
                    app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,i); app.trajInfo.platformTraj.eular(:,i)]);
                    app.MovAnchor = app.MovAnchor.setPosL(app.trajInfo.anchorTraj.pos(:, i)');
                    
                    calculateCable(app);
                    
                    %更新障碍物在轨迹上的位置
                    for n=1:app.obsNumber 
                        if i<=size(app.trajInfo.obsBBTraj{n}, 2) && size(app.trajInfo.obsBBTraj, 2)>0
                            obsTraj = app.trajInfo.obsBBTraj{n};
                            app.obsBB(n) = app.obsBB(n).setOriginPosition(obsTraj(:,i));
                        end
                    end
                    
                    drawCDPR(app);
                    displayObsBB(app);
                    
                    temp = [app.trajInfo.platformTraj.pos(:,i), app.trajInfo.platformTraj.pos(:,1)];
                    plot3(app.UIAxes, temp(1,:), temp(2,:), temp(3,:), '--g');
                    
                    s4 = sprintf("Current speed:\n\t%0.3f m/s\n\n",app.trajInfo.platformTraj.magVel(i));
                    s5 = sprintf("Current acceleration:\n\t%0.3f m/(s^2)\n\n",app.trajInfo.platformTraj.magAccel(i));
                    s6 = sprintf("Current Time:%0.1f s\n", app.trajInfo.time(i));
                    app.TrajectoryInfoTextArea.Value = strcat(s1, s2, s3, s4, s5, s6);
             
                    pause(pauseTime);
                end
                
                %还原障碍物在轨迹上的位置
                for n=1:app.obsNumber 
                    if size(app.trajInfo.obsBBTraj, 2)>0
                        obsTraj = app.trajInfo.obsBBTraj{n};
                        if  size(obsTraj,2)>0
                            app.obsBB(n) = app.obsBB(n).setOriginPosition(obsTraj(:,1));
                        end
                    end
                end
            end
        end
        
        function updateStructureMat(app)
            % update Structure Matrix of CDPR
            for i=1:8
                bi = app.Platform.anchorPosition_G(:, i) - app.Platform.pose_G(1:3);
                app.A_T(:, i) = [app.Cable.UniVector(:, i); cross(bi, app.Cable.UniVector(:, i))];
            end
        end
      
        function CAN_TimerFcn1(app, obj, event)
            % Timer call back Function 1 for CAN application
            % display frame messages in command window
            ID=0;
            TimeStamp=0;
            TimeFlag=0;
            SendType=0;
            RemoteFlag=0;
            ExternFlag=0;
            DataLen=8;
            if app.CAN.CONNECTED~=1
                stop(obj);
                delete(obj);
                clear obj;
            else
                len = VCI_GetReceiveNum(app.CAN.DEVICETYPE, app.CAN.DEVICEINDEX, app.CAN.CANINDEX);
                if len
                     [GetLength,Frames]=VCI_Receive(app.CAN.DEVICETYPE, app.CAN.DEVICEINDEX, app.CAN.CANINDEX, 50,400);
                     if GetLength
                         for i=1:GetLength
                             ID=Frames(i,1);
                             strID=strcat('ID：',sprintf('%08x',ID));
                             TimeStamp=Frames(i,2);
                             strTimeStamp=strcat('       时间标识：',num2str(TimeStamp));
                
                             strDir='       接收';
                    
                             RemoteFlag = Frames(i,4);
                             if RemoteFlag
                                 strRemoteFlag = '      远程帧';
                             else
                                 strRemoteFlag = '      数据帧';
                             end
            
                             ExternFlag = Frames(i,5);
                             if ExternFlag
                                 strExternFlag = '      扩展帧';
                             else
                                 strExternFlag = '      标准帧';
                             end
                             
                             DataLen = Frames(i,7);
                             strDataLen = strcat('       长度：',num2str(DataLen));
                             data1=num2str(dec2hex(Frames(i,8)));
                             data2=num2str(dec2hex(Frames(i,9)));
                             data3=num2str(dec2hex(Frames(i,10)));
                             data4=num2str(dec2hex(Frames(i,11)));
                             data5=num2str(dec2hex(Frames(i,12)));
                             data6=num2str(dec2hex(Frames(i,13)));
                             data7=num2str(dec2hex(Frames(i,14)));
                             data8=num2str(dec2hex(Frames(i,15)));
                             
                             strData=strcat(data1,' -',data2,' -',data3,' -',data4,' -',data5,' -',data6,' -',data7,' -',data8);
      
                             disp(strcat(strID,strTimeStamp,strDir,strRemoteFlag,strExternFlag,strDataLen,'           Data：',strData,'\n'));
                             
                         end
                          app.CAN.RECVFRAMENUM = app.CAN.RECVFRAMENUM + GetLength;%listbox里的帧数加1
                     end
                else
                    
                end
            end
        end
        
        
        function updateMotorsTraj(app)
            % update Motors Trajectory and save into app.trajInfo
            
             %计算动锚点座平均速度 
            app.trajInfo.anchorTraj.averVel = diff(app.trajInfo.anchorTraj.pos')./app.trajInfo.timeStep; %diff计算矩阵行之间的差分，此处转置
            app.trajInfo.anchorTraj.averVel= cat(2, zeros(8,1), app.trajInfo.anchorTraj.averVel'); %再转置，第一列补零
            
            %计算轨道电机位置和速度
            app.trajInfo.railMotorsPos = app.trajInfo.anchorTraj.pos ./ app.S_leadScrew .* (2*pi); %rad
            app.trajInfo.railMotorsSpeed = app.trajInfo.anchorTraj.averVel ./ app.S_leadScrew .*60; %rpm
            app.trajInfo.railMotorsSpeed = abs(app.trajInfo.railMotorsSpeed);
            
            %计算绞盘电机位置和速度
            initialCableLength = app.Cable.OriginLength';
            cableLength_Increment = app.trajInfo.cableTraj.cableLength - repmat(initialCableLength,1,app.trajInfo.Number); %锚点间绳长相对开始点绳长的变化量
            cableLength_Increment = cableLength_Increment + app.trajInfo.anchorTraj.pos; %总绳长变化=锚点间绳长相对开始点绳长的变化量 + 动锚点座位移
            app.trajInfo.winchMotorsPos = cableLength_Increment ./ app.R_winch; %rad
            app.trajInfo.winchMotorsSpeed = cat(2, zeros(8,1), diff(app.trajInfo.winchMotorsPos')') ./app.trajInfo.timeStep .*60 ./ (2*pi); %rpm
            app.trajInfo.winchMotorsSpeed = abs(app.trajInfo.winchMotorsSpeed);
        end
        
        function anchorTrajPlan_M2(app)
            %已知末端轨迹下用优化算法对动锚点座的避障轨迹规划，优化变量为动锚点步长
            if app.ModeDropDown_2.Value == '0' % 在末端轨迹确定好的模式下
                opCount = 0;
    %             app.trajInfo.anchorTraj.pos = zeros(8,app.trajInfo.Number); %预分配内存
    %             app.trajInfo.cableTraj.cableTension = zeros(8,app.trajInfo.Number); %预分配内存
    %             app.trajInfo.cableTraj.cableLength = zeros(8,app.trajInfo.Number); %预分配内存
                app.MovAnchor = app.MovAnchor.setPosL(app.trajInfo.anchorTraj.pos(:,1)); %positon_L归零
                anchorTrajId(1) = 1;
                app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,1); app.trajInfo.platformTraj.eular(:,1)]); %更新末端位姿
                app.calculateCable(); %更新绳索参数
                
                PlatAnchorPosition_Former = app.Platform.anchorPosition_G; %记录初始动平台锚点位置
                Cable_Former = app.Cable; %记录初始绳索计算值
                
                %设置非线性优化函数
                option=optimoptions('fmincon',"Algorithm","interior-point","ConstraintTolerance",1e-8, "MaxFunctionEvaluations", 5000);          
                
                for i=2:app.trajInfo.Number  %从第二个点开始
                    opCount = opCount + 1; %统计点数
                    if opCount == app.trajInfo.anchorTraj.optiFreq %达到优化频率决定的点数
                        opCount = 0; %统计点数归零
                        app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,i); app.trajInfo.platformTraj.eular(:,i)]); %更新末端位姿
                        anchorTrajId(end + 1) = i; %记录采样点的索引
                        
                        %更新障碍物在轨迹上的位置
                        for n=1:app.obsNumber 
                            if i<=size(app.trajInfo.obsBBTraj{n}, 2) && size(app.trajInfo.obsBBTraj, 2)>0
                                obsTraj = app.trajInfo.obsBBTraj{n};
                                app.obsBB(n) = app.obsBB(n).setOriginPosition(obsTraj(:,i));
                            end
                        end
                        
                        %执行优化算法（非线性带约束局部优化）
                        %非线性约束函数2
                        nonlcon = @(X) constrainFunction2(X, app.Platform, PlatAnchorPosition_Former, app.MovAnchor, Cable_Former, ...
                                             app.obsBB, app.obsNumber, app.vertex, app.face, app.R_anchorPully, app.trajInfo.wrenchSetting); %全部约束
                        %优化目标函数
                        switch app.AlgorithmDropDown.Value
                            case '2'
                                fun = @(X) targetFunction2(X, app.Platform, app.MovAnchor, Cable_Former, app.R_anchorPully); %各主轴方向刚度平均值最大
                            case '3'
                                fun = @(X) targetFunction3(X); %各锚点座移动量最小（范数最小）
                        end
                        %优化初值：步长0
                        X0(1:8) = 0; 
                        %解的上下限设置
                        lb = repmat(-app.trajInfo.anchorTraj.maxStep, 8,1);
                        ub = repmat(app.trajInfo.anchorTraj.maxStep, 8,1);
                        X = fmincon(fun,X0,[],[],[],[],lb,ub,nonlcon,option); %执行优化算法
                        
                        app.MovAnchor = app.MovAnchor.setPosL(app.MovAnchor.positon_L + X(1:8)); %根据得到的步长+上一点动锚点位置更新本点动锚点局部位置
                        
                        app.trajInfo.anchorTraj.pos(:,anchorTrajId(end)) = app.MovAnchor.positon_L; %记录本点动锚点位置
                    
                        PlatAnchorPosition_Former = app.Platform.anchorPosition_G; %记录当前动平台锚点位置，供下一次优化计算使用
                        app.calculateCable(); %计算当前绳索参数
                        Cable_Former = app.Cable; %记录当前绳索参数，供下一次优化计算使用
                    end
                end
                
                %后处理
                if app.trajInfo.anchorTraj.optiFreq > 1 %当优化频率点数大于1时进行插值与补偿
                    timeSample = app.trajInfo.time(anchorTrajId);
                    anchorTrajSample = app.trajInfo.anchorTraj.pos(:, anchorTrajId);
                    app.trajInfo.anchorTraj.pos = makima(timeSample, anchorTrajSample, app.trajInfo.time); %对动锚点位置关于时间进行修正Akima分段三次插值
                end
                
                %计算并记录各个轨迹点绳索长度、拉力(刚度)
                TEST = zeros(6,app.trajInfo.Number);
                for i=1:app.trajInfo.Number
                    app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,i); app.trajInfo.platformTraj.eular(:,i)]); %更新末端位姿
                    app.MovAnchor = app.MovAnchor.setPosL(app.trajInfo.anchorTraj.pos(:, i)); %更新动锚点坐标
                    app.calculateCable(); %计算绳长、滑轮位姿
                    app.trajInfo.cableTraj.cableLength(:, i) = app.Cable.Length; %记录绳长
                    
                    %执行力分配算法，计算拉力分配
                    updateStructureMat(app); %更新结构矩阵
                    [app.Cable.tensionDistrib, ~] = forceDistrib1_Improved(app.A_T, app.trajInfo.wrenchSetting); %计算张力分布
                    app.trajInfo.cableTraj.cableTension(:,i) = app.Cable.tensionDistrib; %记录张力分布
                    TEST(:,i) = app.A_T * app.Cable.tensionDistrib;
                    
                    %当使用刚度作为优化准则时计算主轴刚度
                    if app.AlgorithmDropDown.Value == '2'
                        app.trajInfo.Stiffness(:,i) = diag(app.calculateStiffness,0); %计算并提取刚度矩阵的主对角元素
                    end
                end
                
                %计算更新并存储各电机轨迹
                app.updateMotorsTraj();
                
                %动画演示
                playTrajInfo(app);
            end
        end
        
        function K_e = calculateStiffness(app)
            % update cable stiffness and calculate end-effector stiffness matrix
            for i =1:8
                app.Cable.K(i) = app.Cable.E * app.Cable.A / (app.Cable.Length(i) + app.Cable.Fixed_Length(i) + app.MovAnchor.positon_L(i));
            end
            K_e = app.A_T * diag(app.Cable.K) * app.A_T';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CDPR_ParaInit(app);
            updatePlatform(app);
            calculateCable(app);
            updateStructureMat(app);
            drawCDPR(app);           
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            temp = app.XEditField.Value * 1e-3;
            if temp<=app.Platform.boundary(1,1) || temp>=app.Platform.boundary(1,2)
                app.XEditField.Value = 0;
                s = sprintf("X value lies in (%d,%d)!\ntry again",app.Platform.boundary(1,1)*1e3,app.Platform.boundary(1,2)*1e3);
                app.WarningTextArea.Value = s;
            else
                app.startPose(1) = temp;
                app.WarningTextArea.Value = '';
            end 
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            temp = app.YEditField.Value * 1e-3;
            if temp<=app.Platform.boundary(2,1) || temp>=app.Platform.boundary(2,2)
                app.YEditField.Value = 0;
                s = sprintf("Y value lies in (%d,%d)!\ntry again",app.Platform.boundary(2,1)*1e3,app.Platform.boundary(2,2)*1e3);
                app.WarningTextArea.Value = s;
            else
                app.startPose(2) = temp;
                app.WarningTextArea.Value = '';
            end
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            temp = app.ZEditField.Value * 1e-3;
            if temp<=app.Platform.boundary(3,1) || temp>=app.Platform.boundary(3,2)
                app.ZEditField.Value = 37;
                s = sprintf("Z value lies in (%d,%d)!\ntry again",app.Platform.boundary(3,1)*1e3,app.Platform.boundary(3,2)*1e3);
                app.WarningTextArea.Value = s;
            else
                app.startPose(3) = temp;
                app.WarningTextArea.Value = '';
            end
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            app.startPose(4) = app.aEditField.Value * pi/180;
            
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            app.startPose(5) = app.bEditField.Value * pi/180;
            
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.startPose(6) = app.yEditField.Value * pi/180;
            
        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
            temp = app.XEditField_2.Value * 1e-3;
            if temp<=app.Platform.boundary(1,1) || temp>=app.Platform.boundary(1,2)
                app.XEditField_2.Value = 0;
                s = sprintf("X value lies in (%d,%d)!\ntry again",app.Platform.boundary(1,1)*1e3,app.Platform.boundary(1,2)*1e3);
                app.WarningTextArea.Value = s;
            else
                app.endPose(1) = temp;
                app.WarningTextArea.Value = '';
            end
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            temp = app.YEditField_2.Value * 1e-3;
            if temp<=app.Platform.boundary(2,1) || temp>=app.Platform.boundary(2,2)
                app.YEditField_2.Value = 0;
                s = sprintf("Y value lies in (%d,%d)!\ntry again",app.Platform.boundary(2,1)*1e3,app.Platform.boundary(2,2)*1e3);
                app.WarningTextArea.Value = s;
            else
                app.endPose(2) = temp;
                app.WarningTextArea.Value = '';
            end
        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)
            temp = app.ZEditField_2.Value * 1e-3;
            if temp<=app.Platform.boundary(3,1) || temp>=app.Platform.boundary(3,2)
                app.ZEditField_2.Value = 37;
                s = sprintf("Z value lies in (%d,%d)!\ntry again",app.Platform.boundary(3,1)*1e3,app.Platform.boundary(3,2)*1e3);
                app.WarningTextArea.Value = s;
            else
                app.endPose(3) = temp;
                app.WarningTextArea.Value = '';
            end
        end

        % Value changed function: aEditField_2
        function aEditField_2ValueChanged(app, event)
            app.endPose(4) = app.aEditField_2.Value * pi/180;
            
        end

        % Value changed function: bEditField_2
        function bEditField_2ValueChanged(app, event)
            app.endPose(5) = app.bEditField_2.Value * pi/180;
            
        end

        % Value changed function: yEditField_2
        function yEditField_2ValueChanged(app, event)
            app.endPose(6) = app.yEditField_2.Value * pi/180;
            
        end

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
           % plan 5-order linear trajectory of the platform
           platLinearTrajPlan(app);
            
           % play the trajectory animation     
           playTrajInfo(app);
        end

        % Value changed function: starttimeEditField
        function starttimeEditFieldValueChanged(app, event)
            app.startTime = app.starttimeEditField.Value;
            
        end

        % Value changed function: endtimeEditField
        function endtimeEditFieldValueChanged(app, event)
            app.endTime = app.endtimeEditField.Value;
            
        end

        % Value changed function: timestepEditField
        function timestepEditFieldValueChanged(app, event)
            app.timeStep = app.timestepEditField.Value;
            app.trajInfo.timeStep = app.timeStep;
            app.PlanningtimestepEditField.Value = app.timeStep;
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if strcmp(value, "specify time")
                app.trajPlanMode = 0;
                app.starttimeEditField.Value = 0; app.startTime = app.starttimeEditField.Value;
                app.timestepEditField.Value = 0.1; app.timeStep = app.timestepEditField.Value;
                app.endtimeEditField.Value = 5; app.endTime = app.endtimeEditField.Value;
                app.MaxvEditField.Visible = "off"; app.MaxvEditFieldLabel.Visible = "off";
                app.MaxaEditField.Visible = "off"; app.MaxaEditFieldLabel.Visible = "off";
                app.endtimeEditField.Visible = "on"; app.endtimeEditFieldLabel.Visible = "on";
                app.ms2Label.Visible = "off";
                app.sLabel_2.Text = "s";
            elseif strcmp(value, "specify max speed and acceleration")
                app.trajPlanMode = 1;
                app.starttimeEditField.Value = 0; app.startTime = app.starttimeEditField.Value;
                app.timestepEditField.Value = 0.1; app.timeStep = app.timestepEditField.Value;
                app.MaxvEditField.Value = 0.4; app.trajInfo.platformTraj.maxVelBound = app.MaxvEditField.Value;
                app.MaxaEditField.Value = 0.2; app.trajInfo.platformTraj.maxAccelBound = app.MaxaEditField.Value;
                app.MaxvEditField.Visible = "on"; app.MaxvEditFieldLabel.Visible = "on";
                app.MaxaEditField.Visible = "on"; app.MaxaEditFieldLabel.Visible = "on";
                app.endtimeEditField.Visible = "off"; app.endtimeEditFieldLabel.Visible = "off";
                app.ms2Label.Visible = "on";
                app.sLabel_2.Text = "m/s";
            end
        end

        % Value changed function: MaxvEditField
        function MaxvEditFieldValueChanged(app, event)
            app.trajInfo.platformTraj.maxVelBound = app.MaxvEditField.Value; 
            
        end

        % Value changed function: MaxaEditField
        function MaxaEditFieldValueChanged(app, event)
            app.trajInfo.platformTraj.maxAccelBound = app.MaxaEditField.Value;
            
        end

        % Value changed function: lengthmmEditField
        function lengthmmEditFieldValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB') 
                length = app.lengthmmEditField.Value * 1e-3;
                ID = str2double(app.ObstacleIDDropDown.Value);
                app.obsBB(ID) = app.obsBB(ID).setEdgeLength(length, 'length');
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Value changed function: widthmmEditField
        function widthmmEditFieldValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB') 
                width = app.widthmmEditField.Value * 1e-3;
                ID = str2double(app.ObstacleIDDropDown.Value);
                app.obsBB(ID) = app.obsBB(ID).setEdgeLength(width, 'width');
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Value changed function: heightmmEditField
        function heightmmEditFieldValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB') 
                height = app.heightmmEditField.Value * 1e-3;
                ID = str2double(app.ObstacleIDDropDown.Value);
                app.obsBB(ID) = app.obsBB(ID).setEdgeLength(height, 'height');
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Button pushed function: GenerateObstacleTrajectoryButton
        function GenerateObstacleTrajectoryButtonPushed(app, event)
            %生成障碍物的梯形加减速轨迹
            obsID = str2double(app.ObstacleIDDropDown.Value);
            obsSpeed = norm(app.obsBB(obsID).velocity); %障碍物速度
            obsAccel = app.obsBB(obsID).acceleration; %障碍物加速度
            obsRunTime = app.RuntimeOfObstacleEditField.Value; %障碍物运行时间
            time_Step = app.PlanningtimestepEditField.Value;  %障碍物规划时间步
            K = 0:time_Step:obsRunTime; %障碍物规划时间序列
            obsBBTraj = zeros(3,1); %预定义
            
            if obsSpeed == 0 %当障碍物速度为零时
                obsBBTraj(:,1) = app.obsBB(obsID).origin; %只记录一个当前位置点
                
            else %当障碍物速度不为零时
                obsBBTraj = zeros(3,size(K,2)); %预定义
                for i=1:size(K,2) %依次存储障碍物位置点数据
                    dirVector = app.obsBB(obsID).velocity/obsSpeed; %速度方向单位向量
                    t_Accel = obsSpeed/obsAccel; %匀加速用时间
                    t_Const = obsRunTime - 2*t_Accel; %匀速运动时间
                    t_Now = K(i); %当前时间
                    
                    if t_Const <= 0 %当没有匀速运动时间时
                        s_Accel = 0.5*obsAccel*(obsRunTime/2)^2; %加速度段走过的距离
                        if t_Now <= obsRunTime/2
                            obsBBTraj(:,i) = app.obsBB(obsID).origin + dirVector.*0.5*obsAccel*t_Now^2; %更新轨迹点
                        else
                            v_max = obsAccel*(obsRunTime/2);
                            obsBBTraj(:,i) = app.obsBB(obsID).origin + dirVector.*(s_Accel + (t_Now-t_Accel)*v_max - 0.5*obsAccel*(t_Now-t_Accel)^2);
                        end
                    else %当有匀速运动时间时
                        s_Accel = 0.5*obsAccel*t_Accel^2; %加速度段走过的距离
                        if t_Now <= t_Accel
                            obsBBTraj(:,i) = app.obsBB(obsID).origin + dirVector.*0.5*obsAccel*t_Now^2; %更新轨迹点
                        elseif (t_Now>t_Accel) && (t_Now<=(t_Const+t_Accel))
                            obsBBTraj(:,i) = app.obsBB(obsID).origin + dirVector.*(s_Accel + (t_Now-t_Accel)*obsSpeed);
                        else
                            obsBBTraj(:,i) = app.obsBB(obsID).origin + dirVector.*(2*s_Accel + t_Const*obsSpeed - 0.5*obsAccel*(obsRunTime-t_Now)^2);
                        end
                    end
                end
            end
            app.trajInfo.obsBBTraj{obsID} = obsBBTraj;
            app.TrajectoryInfoTextArea.Value = sprintf("obstacle trajectory successfully generated!\n(trapezoidal planning)!");
            
            % 动画演示障碍物轨迹后，重新回到原位
            if obsSpeed ~= 0
                pauseTime = time_Step*0.2;
                for i=1:size(K,2)
                   app.obsBB(obsID) = app.obsBB(obsID).setOriginPosition(obsBBTraj(:,i));
                    app.drawCDPR();
                    app.displayObsBB();
                    
                    pause(pauseTime);
                end
                
                pause(3);%3秒后回原位
                app.obsBB(obsID) = app.obsBB(obsID).setOriginPosition(obsBBTraj(:,1));
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Button pushed function: clearobstaclesButton
        function clearobstaclesButtonPushed(app, event)
            app.obsBB=ObsBB();
            app.obsNumber=0;
            app.ObstacleIDDropDown.Items = app.ObstacleIDDropDown.Items(end);
            app.ObstacleIDDropDown.Value = app.ObstacleIDDropDown.Items(end);
            app.Platform.poDistance = [];
            app.drawCDPR();
        end

        % Button pushed function: ResetPositionButton
        function ResetPositionButtonPushed(app, event)
            app.Platform = app.Platform.setPoseG(app.Platform.originPose_G);
            app.MovAnchor = app.MovAnchor.resetAllPos();
            
            app.calculateCable();
            app.drawCDPR();
            
            %还原障碍物在轨迹上的位置
            for n=1:app.obsNumber 
                if size(app.trajInfo.obsBBTraj, 2)>0
                    obsTraj = app.trajInfo.obsBBTraj{n};
                    if  size(obsTraj,2)>0
                        app.obsBB(n) = app.obsBB(n).setOriginPosition(obsTraj(:,1));
                    end
                end
            end
            app.displayObsBB();
        end

        % Value changed function: OptimizationFreqEditField
        function OptimizationFreqEditFieldValueChanged(app, event)
            app.trajInfo.anchorTraj.optiFreq = app.OptimizationFreqEditField.Value;
            
        end

        % Value changed function: maxanchorstepEditField
        function maxanchorstepEditFieldValueChanged(app, event)
            app.trajInfo = app.trajInfo.setAnchorMaxStep(app.maxanchorstepEditField.Value);
            
        end

        % Value changed function: maxanchorspeedEditField
        function maxanchorspeedEditFieldValueChanged(app, event)
            app.trajInfo = app.trajInfo.setAnchorMaxVel(app.maxanchorspeedEditField.Value);
            
        end

        % Button pushed function: specifypathforendeffectorButton
        function specifypathforendeffectorButtonPushed(app, event)
            if app.ModeDropDown_2.Value=='0'
                app.trajInfo.platformTraj.maxVelBound = app.MaxvEditField.Value; %m/s
                app.trajInfo.platformTraj.maxAccelBound = app.MaxaEditField.Value; %m/(s^2)
                app.trajInfo = app.trajInfo.setAnchorMaxVel(app.maxanchorspeedEditField.Value); %m/s
                app.trajInfo = app.trajInfo.setAnchorMaxStep(app.maxanchorstepEditField.Value); %m
                app.trajInfo.anchorTraj.optiFreq = app.OptimizationFreqEditField.Value;
                app.platLinearTrajPlan();
            end
        end

        % Callback function
        function showcurrentobstaclesButtonPushed(app, event)
            for i=1:app.obsNumber
                app.obsBB(i).plot(app.UIAxes);
            end
        end

        % Value changed function: showobstaclesgeneratedCheckBox
        function showobstaclesgeneratedCheckBoxValueChanged(app, event)
            value = app.showobstaclesgeneratedCheckBox.Value;
            if value && app.obsNumber==0
                app.WarningTextArea.Value = "there's no obstacle generated!";
            elseif value
                displayObsBB(app);
            else
                drawCDPR(app);
            end
        end

        % Value changing function: azimuthSlider
        function azimuthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.drawCDPR();
            displayObsBB(app);
            view(app.UIAxes, changingValue, app.elevationSlider.Value);
        end

        % Value changed function: azimuthSlider
        function azimuthSliderValueChanged(app, event)
            value = app.azimuthSlider.Value;
            app.drawCDPR();
            displayObsBB(app);
            view(app.UIAxes, value, app.elevationSlider.Value);
        end

        % Value changed function: elevationSlider
        function elevationSliderValueChanged(app, event)
            value = app.elevationSlider.Value;
            app.drawCDPR();
            displayObsBB(app);
            view(app.UIAxes, app.azimuthSlider.Value, value);
        end

        % Value changing function: elevationSlider
        function elevationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.drawCDPR();
            displayObsBB(app);
            view(app.UIAxes, app.azimuthSlider.Value, changingValue);
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            app.azimuthSlider.Value = -37.5;
            app.elevationSlider.Value = 30;
            view(app.UIAxes, app.azimuthSlider.Value, app.elevationSlider.Value);
        end

        % Button pushed function: updatestartposeButton
        function updatestartposeButtonPushed(app, event)
            app.Platform = app.Platform.setPoseG(app.startPose);
            app.MovAnchor = app.MovAnchor.resetAllPos();
            
            calculateCable(app);
            updateStructureMat(app);
            drawCDPR(app);
            
            %还原障碍物在轨迹上的位置
            for n=1:app.obsNumber 
                if size(app.trajInfo.obsBBTraj, 2)>0
                    obsTraj = app.trajInfo.obsBBTraj{n};
                    if  size(obsTraj,2)>0
                        app.obsBB(n) = app.obsBB(n).setOriginPosition(obsTraj(:,1));
                    end
                end
            end
            displayObsBB(app);
            
            %检查力分布算法的解是否达标
            %%力分配算法求解拉力
            [~,  feasible] = forceDistrib1_Improved(app.A_T, app.trajInfo.wrenchSetting); 
            if feasible %力分配结果可行
                app.WarningTextArea.Value = 'Start Pose Force Feasible!';
            else %C中有零元素
                app.WarningTextArea.Value = 'Start Pose Force Infeasible! Please Refind A New Start Pose!';
            end
        end

        % Button pushed function: saveobstaclesButton
        function saveobstaclesButtonPushed(app, event)
            saved_obsBB = app.obsBB;
            
            %还原障碍物在轨迹上的位置
            for n=1:app.obsNumber 
                if size(app.trajInfo.obsBBTraj, 2)>0
                    obsTraj = app.trajInfo.obsBBTraj{n};
                    if  size(obsTraj,2)>0
                        saved_obsBB(n) = saved_obsBB(n).setOriginPosition(obsTraj(:,1));
                    end
                end
            end
            
            if ~exist('saved obstacles','dir') %if folder not exist,make new folder
                mkdir('saved obstacles');
            end
            
            if app.resultnameEditField_3.Value == ""
                save("saved obstacles\savedObstacles", "saved_obsBB");
            else
                fileName = strcat("saved obstacles\", app.resultnameEditField_3.Value);
                save(fileName, "saved_obsBB");
            end
        end

        % Button pushed function: loadobstaclesButton
        function loadobstaclesButtonPushed(app, event)
            if app.resultnameEditField_2.Value == ""
                app.WarningTextArea.Value = sprintf("empty!\ntype name of the saved obstacle to load!");
            else
                fileName = strcat("saved obstacles\", app.resultnameEditField_2.Value);
                load(fileName);
                app.obsBB = saved_obsBB;
                app.obsNumber = size(saved_obsBB, 2);
                displayObsBB(app);
                
                app.ObstacleIDDropDown.Items = {};
                for i=1:app.obsNumber
                    app.ObstacleIDDropDown.Items{i} = num2str(i);
                end
                app.ObstacleIDDropDown.Items{app.obsNumber+1} = 'Add New ObsBB';
                app.ObstacleIDDropDown.Value = app.ObstacleIDDropDown.Items{1};
                
                ID = 1;
                app.lengthmmEditField.Value = app.obsBB(ID).length*1e3;
                app.widthmmEditField.Value = app.obsBB(ID).width*1e3;
                app.heightmmEditField.Value = app.obsBB(ID).height*1e3;
                app.XmmSpinner.Value = app.obsBB(ID).origin(1)*1e3;
                app.YmmSpinner.Value = app.obsBB(ID).origin(2)*1e3;
                app.ZmmSpinner.Value = app.obsBB(ID).origin(3)*1e3;
                app.SpeedOfObstacleEditField.Value = norm(app.obsBB(ID).velocity);
                
                if app.SpeedOfObstacleEditField.Value >= 1e-6
                    directionVector = app.obsBB(ID).velocity / app.SpeedOfObstacleEditField.Value;
                else
                    directionVector = [0;0;0];
                end
                app.DirectionAngle1Spinner.Value = acosd(directionVector(3));
                app.DirectionAngle2Spinner.Value = acosd(directionVector(1) / sind(app.DirectionAngle1Spinner.Value));
                
                app.AccelerationOfObstacleEditField.Value = app.obsBB(ID).acceleration;
                app.AddButton.Text = 'Delete';
                
                app.minDisToCableButton.Visible = 'on';
                app.minDisToCableButton.Enable = 'on';
                app.GenerateObstacleTrajectoryButton.Visible = 'on';
                app.GenerateObstacleTrajectoryButton.Enable = 'on';
            end
        end

        % Button pushed function: saveresultButton
        function saveresultButtonPushed(app, event)
            saved_result = app.trajInfo;
                                    
            if ~exist('saved simulation results','dir') %if folder not exist,make new folder
                mkdir('saved simulation results');
            end
            
            if app.resultnameEditField_4.Value == ""
                save("saved simulation results\savedResults", "saved_result");
            else
                fileName = strcat("saved simulation results\", app.resultnameEditField_4.Value);
                save(fileName, "saved_result");
            end
        end

        % Button pushed function: loadresultButton
        function loadresultButtonPushed(app, event)
            if app.resultnameEditField.Value == ""
                app.WarningTextArea.Value = sprintf("empty!\ntype name of the saved result to load!");
            else
                fileName = strcat("saved simulation results\", app.resultnameEditField.Value);
                load(fileName);
                
                app.trajInfo = saved_result;
                app.XEditField.Value = app.trajInfo.platformTraj.pos(1,1)*1e3; app.XEditField_2.Value = app.trajInfo.platformTraj.pos(1,end)*1e3;
                app.YEditField.Value = app.trajInfo.platformTraj.pos(2,1)*1e3; app.YEditField_2.Value = app.trajInfo.platformTraj.pos(2,end)*1e3;
                app.ZEditField.Value = app.trajInfo.platformTraj.pos(3,1)*1e3; app.ZEditField_2.Value = app.trajInfo.platformTraj.pos(3,end)*1e3;
                app.aEditField.Value = app.trajInfo.platformTraj.eular(1,1)*180/pi; app.aEditField_2.Value = app.trajInfo.platformTraj.eular(1,end)*180/pi;
                app.bEditField.Value = app.trajInfo.platformTraj.eular(2,1)*180/pi; app.bEditField_2.Value = app.trajInfo.platformTraj.eular(2,end)*180/pi;
                app.yEditField.Value = app.trajInfo.platformTraj.eular(3,1)*180/pi; app.yEditField_2.Value = app.trajInfo.platformTraj.eular(3,end)*180/pi;
            
                app.startPose = [app.trajInfo.platformTraj.pos(:,1); app.trajInfo.platformTraj.eular(:,1)];
                app.endPose = [app.trajInfo.platformTraj.pos(:,end); app.trajInfo.platformTraj.eular(:,end)];
        
                app.OptimizationFreqEditField.Value = app.trajInfo.anchorTraj.optiFreq;
                app.maxanchorstepEditField.Value = app.trajInfo.anchorTraj.maxStepBound;
                app.maxanchorspeedEditField.Value = app.trajInfo.anchorTraj.maxAverVelBound;
                
                app.MaxTensionEditField.Value = app.trajInfo.wrenchSetting.feaspTensionRange(2);
                app.MinTensionEditField.Value = app.trajInfo.wrenchSetting.feaspTensionRange(1);
                app.UITable.Data = app.trajInfo.wrenchSetting.wrench';
                
                app.ModeDropDown_2.Value = app.trajInfo.PlanMode;
                app.AlgorithmDropDown.Value = app.trajInfo.PlanAlgorithm;
            end
            
        end

        % Button pushed function: playtheresultButton
        function playtheresultButtonPushed(app, event)
            playTrajInfo(app);
        end

        % Button pushed function: generatesimulationresultButton
        function generatesimulationresultButtonPushed(app, event)
            if app.ModeDropDown_2.Value == '0' %末端轨迹已规划好
                if app.AlgorithmDropDown.Value == '1' %拉力分布优化算法
                    anchorTrajPlan_M1(app);
                else
                    anchorTrajPlan_M2(app); %刚度或位移优化算法
                end
            end
            
            app.trajInfo.PlanMode = app.ModeDropDown_2.Value;
            app.trajInfo.PlanAlgorithm = app.AlgorithmDropDown.Value;
        end

        % Button pushed function: PlotForceDistributionGraphButton
        function PlotForceDistributionGraphButtonPushed(app, event)
            f1=figure('Name','Force Distribution Graph','NumberTitle','off');
            axe1 = axes(f1);
            dispLengend = cell(1,8);
            for i=1:8
                dispLengend{i} = strcat("Cable ", num2str(i));
                plot(axe1, app.trajInfo.time, app.trajInfo.cableTraj.cableTension(i,:));
                hold on;
            end
            legend(axe1,dispLengend,"FontSize",14);
            xlabel(axe1, 'time(s)', "FontSize", 15);
            ylabel(axe1, 'tension(N)', "FontSize", 15);
            title(axe1,"Force Distribution Graph", "FontSize", 15);
        end

        % Button pushed function: ConnectCANButton
        function ConnectCANButtonPushed(app, event)
            if app.CAN.CONNECTED ~=1
                if app.CAN.openDevice()  
                    app.ConnectCANButton.Text = "Disconnect CAN";
                    
                    if  initCAN(app.CAN)~=1                
                        app.WarningTextArea.Value= 'Init CAN失败！';
                    end
                else
                    app.WarningTextArea.Value= 'Open CAN失败！';
                end
            else
                if app.CAN.closeDevice() ~= 1
                    app.WarningTextArea.Value= '关闭CAN失败！';
                else
                    app.ConnectCANButton.Text = "Connect CAN";
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.CAN.CONNECTED
                if app.CAN.closeDevice() ~= 1
                app.WarningTextArea.Value= '关闭CAN失败！';
                end
            end
            app.UR5.DisconnectUR5();
            delete(app);
        end

        % Value changed function: CANIndexDropDown
        function CANIndexDropDownValueChanged(app, event)
            value = app.CANIndexDropDown.Value;
            app.CAN.CANINDEX = str2double(value);
        end

        % Value changed function: BaudRateDropDown
        function BaudRateDropDownValueChanged(app, event)
            value = app.BaudRateDropDown.Value;
            if app.CAN.setBaudRate(value) ~= 1
                app.WarningTextArea.Value = 'unknown Baud rate!';
            end
        end

        % Button pushed function: StartCANButton
        function StartCANButtonPushed(app, event)
            if startCAN(app.CAN)~= 1
                app.WarningTextArea.Value='启动CAN失败！';
            else
%                 t = timer;
%                 t.StartFcn = @(~,~) disp("start receiving and displaying frames");
%                 t.TimerFcn = @(obj, thisEvent) CAN_TimerFcn1(app, obj, thisEvent); % CAN timer call back function
%                 t.StopFcn = @(obj,~) delete(obj);
%                 t.Period = 0.5;
%                 t.ExecutionMode = 'fixedDelay';
%                 start(t);

                %停止驱动器发送消息
                for i=1:8
                    app.winchMotors(i).requestMotorInfo(0,0);
                    pause(10*1e-3);
                    app.railMotors(i).requestMotorInfo(0,0);
                    pause(10*1e-3);
                end
            end
        end

        % Button pushed function: ResetCANButton
        function ResetCANButtonPushed(app, event)
            if resetCAN(app.CAN)~= 1
                app.WarningTextArea.Value= '复位CAN失败！';
            end
        end

        % Button pushed function: ResetDriverButton
        function ResetDriverButtonPushed(app, event)
            MotorID = app.IndexEditField.Value;
            switch app.MotorTypeDropDown.Value
                case '0'
                    app.winchMotors(MotorID).resetDriver(false);
                case '1'    
                    app.railMotors(MotorID).resetDriver(false);
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            Position=app.RoundEditField.Value*2*pi;
            Speed = app.SpeedEditField.Value;
            MotorID = app.IndexEditField.Value;
            
            switch app.MotorTypeDropDown.Value
                case '0'
                    app.winchMotors(MotorID).setMotorMotion(Position, Speed, false);
                case '1'    
                    app.railMotors(MotorID).setMotorMotion(Position, Speed, false);
            end
        end

        % Button pushed function: SetModeButton
        function SetModeButtonPushed(app, event)
            MotorID = app.IndexEditField.Value;
            switch app.MotorTypeDropDown.Value
                case '0'
                    app.winchMotors(MotorID).setDriverMode(str2double(app.SetModeDropDown.Value), false);
                case '1'    
                    app.railMotors(MotorID).setDriverMode(str2double(app.SetModeDropDown.Value), false);
            end
        end

        % Value changed function: RoundEditField
        function RoundEditFieldValueChanged(app, event)
            value = app.RoundEditField.Value;
            
        end

        % Button pushed function: TestButton_2
        function TestButton_2Pushed(app, event)
            pause('on');
            anchorID = app.AnchorIDEditField.Value;
            %5-order linear planning
            originPosition = app.MovAnchor.positon_L;
            targetPosition = originPosition;
            targetPosition(anchorID) = targetPosition(anchorID) + app.SetDisplacementEditField.Value;
            [RailPos, RailVel, ~, ~, ~, N] = trajPlan2([originPosition(anchorID);0;0;0;0;0], [targetPosition(anchorID);0;0;0;0;0],...
                                            app.MaxSpeedEditField.Value, app.MaxAccelEditField.Value, 0, 0.1);
                                        
            RailPos = RailPos(1,:); RailVel = RailVel(1,:);
            
            %计算绳长变化
            CableTraj=zeros(1,N);
            pos_Now = originPosition;
            for i=1:N
                pos_Now(anchorID) = RailPos(i);
                app.MovAnchor = app.MovAnchor.setPosL(pos_Now);
                app.calculateCable();
                CableTraj(i) = app.Cable.Length(anchorID);
            end
            CableTraj = CableTraj-repmat(app.Cable.OriginLength(anchorID),1,N); %计算锚点间绳长相对变化量
            %更新图像
            drawCDPR(app);
            displayObsBB(app);
            %计算绞盘电机转角
            CableChange = CableTraj + RailPos;
            WinchMotorPos = CableChange ./ app.R_winch; %rad
            %计算绞盘电机速度
            WinchMotorVel = diff(WinchMotorPos)./0.1; %rad/s
            WinchMotorVel = cat(2, 0, WinchMotorVel);
            WinchMotorVel = abs(WinchMotorVel * 60 / (2*pi)); %rpm
            %计算轨道电机转角和速度
            RailMotorPos = RailPos ./ app.S_leadScrew * 2*pi; %rad
            RailMotorVel = abs(RailVel ./ app.S_leadScrew *60); %rpm
            
            for i=1:N
                app.winchMotors(anchorID).setMotorMotion(WinchMotorPos(i), WinchMotorVel(i)*1.2, true); %添加绞盘电机目标速度位置到CAN发送队列
                app.railMotors(anchorID).setMotorMotion(RailMotorPos(i), RailMotorVel(i)*1.2, true); %添加轨道电机目标速度位置到CAN发送队列
                app.CAN.sendTransQueue(); %发送并重置CAN队列
                pause(0.1); 
            end
        end

        % Button pushed function: ResetModuleButton
        function ResetModuleButtonPushed(app, event)
            pause('on');
            anchorID = app.AnchorIDEditField.Value;
            
            app.winchMotors(anchorID).resetDriver(true); %绞盘电机驱动器重置
            app.railMotors(anchorID).resetDriver(true); %轨道电机驱动器重置
            app.CAN.sendTransQueue();
            pause(0.6); %复位大约需要500ms
            
            app.winchMotors(anchorID).setDriverMode(1, true); %绞盘电机驱动器速度-位置模式
            app.railMotors(anchorID).setDriverMode(1, true); %轨道电机驱动器速度-位置模式
            app.CAN.sendTransQueue();
            pause(0.1);
            
            app.calculateCable(); %calculate current cable length between anchors
            app.Cable.OriginLength(anchorID) = app.Cable.Length(anchorID); %record current cable length as the original cable length
        end

        % Button pushed function: GoToStartPosespecifiedButton
        function GoToStartPosespecifiedButtonPushed(app, event)
            %若当前在仿真结果的末端轨迹，则按仿真结果原路返回起点，否则重新规划到起点
            if app.trajInfo.Number>1 && norm(app.Platform.pose_G - [app.trajInfo.platformTraj.pos(:,end);app.trajInfo.platformTraj.eular(:,end)])==0 ...
                     && norm(app.MovAnchor.positon_L - app.trajInfo.anchorTraj.pos(:,end)')==0 ... 
                     && size(app.trajInfo.winchMotorsPos, 2)>0 && size(app.trajInfo.railMotorsPos, 2)>0 %当前末端位置、动锚点位置与仿真结果终点是否重合、仿真结果是否规划好了电机轨迹
                
                 %使障碍物原路返回
                if ~strcmp(app.ObstacleIDDropDown.Value,'Add New ObsBB')
                    ID = str2double(app.ObstacleIDDropDown.Value);
                    if norm(app.obsBB(ID).velocity)>0 && size(app.trajInfo.obsBBTraj,2)>=ID && size(app.trajInfo.obsBBTraj{ID},2)>1
                        obsTraj = app.trajInfo.obsBBTraj{ID};
                        position = app.T_G2UR5 * [obsTraj(:,1); 1];
                        position = position(1:3);
                        position(3) = position(3) + app.obsBB(ID).height/2 + app.linkOffset_ObsBB; %add offset to TCP
                        targetPose = [position; app.TCP_CaliPose_RotVec(4:6)];
                        vel = norm(app.obsBB(ID).velocity); %m/s
                        accel = app.obsBB(ID).acceleration; %m/(s^2)
                        app.UR5.moveL_Trapez(targetPose, vel, accel);
                        
                        % 障碍物设为原位
                        app.obsBB(ID) = app.obsBB(ID).setOriginPosition(obsTraj(:,1));
                    else
                        app.WarningTextArea.Value = 'This obstacle is static,or no trajectory generated';
                    end
                else
                    app.WarningTextArea.Value = 'Please choose an obstacle ID!';
                end
                 
                for i=2:app.trajInfo.Number
                    k = app.trajInfo.Number+1-i;
                    for ID=1:8
                        app.winchMotors(ID).setMotorMotion(app.trajInfo.winchMotorsPos(ID,k), app.trajInfo.winchMotorsSpeed(ID,k)*1.1, true); %添加绞盘电机目标速度位置到CAN发送队列
                        app.railMotors(ID).setMotorMotion(app.trajInfo.railMotorsPos(ID,k), app.trajInfo.railMotorsSpeed(ID,k)*1.1, true); %添加轨道电机目标速度位置到CAN发送队列
                    end
                    app.CAN.sendTransQueue(); %发送并重置CAN队列
                    pause(app.trajInfo.timeStep);
                end
                
                %更新图像
                app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,1); app.trajInfo.platformTraj.eular(:,1)]);
                app.MovAnchor = app.MovAnchor.setPosL(app.trajInfo.anchorTraj.pos(:,1));
                app.calculateCable();
                app.drawCDPR();
                app.displayObsBB();
                
            else
                pause('on');
                t_Step = 0.1; %s
                vmax = 0.05; %m/s
                amax = 0.025; %m/(s^2)
                intervalPose = app.Platform.pose_G;
                intervalPose(3) = app.startPose(3); %中间姿态（只取Z轴的量，第一段轨迹只上升或下降）
                %生成第一段轨迹 + 动锚点座返回原位轨迹
                [pos1, ~, ~, eular1, ~, N1] = trajPlan1(app.Platform.pose_G, intervalPose, 0, 15, t_Step); %与动锚点同步运动时间设为8秒
                % 规划动锚点
                originPosition = app.MovAnchor.positon_L;
                targetPosition = zeros(1,8); % 初始局部位置
                MovAchorPos = zeros(8,N1); %预定义
                MovAchorVel = zeros(8,N1); %预定义
                for anchorID=1:8
                    [RailPos, RailVel, ~, ~, ~, ~] = trajPlan1([originPosition(anchorID);0;0;0;0;0], [targetPosition(anchorID);0;0;0;0;0],...
                                                                 0, 15, t_Step); %与轨迹1同步运动时间设为8秒
                    MovAchorPos(anchorID,:) = RailPos(1,:); MovAchorVel(anchorID,:) = RailVel(1,:); %记录8个锚点座位置和速度
                end
                
                %计算第一段轨迹绳长变化
                CableTraj1=zeros(8,N1);
                for i=1:N1
                    app.Platform = app.Platform.setPoseG([pos1(:,i); eular1(:, i)]);
                    app.MovAnchor = app.MovAnchor.setPosL(MovAchorPos(:,i)'); %更新锚点座位置
                    app.calculateCable();
                    CableTraj1(1:end, i) = app.Cable.Length + app.MovAnchor.positon_L;
                end
                CableTraj1 = CableTraj1-repmat(app.Cable.OriginLength',1,N1); %计算锚点间绳长相对变化量
                
                %计算第一段轨迹绞盘电机转角
                WinchMotorPos1 = CableTraj1 ./ app.R_winch; %rad
                RailMotorPos = MovAchorPos ./ app.S_leadScrew * 2*pi; %rad
                %计算第一段绞盘电机速度
                WinchMotorVel1 = diff(WinchMotorPos1')./ t_Step; %rad/s,diff计算矩阵行之间的差分，此处转置
                WinchMotorVel1 = cat(2, zeros(8,1), WinchMotorVel1'); %再转置，第一列补零
                WinchMotorVel1 = abs(WinchMotorVel1 * 60 / (2*pi)) * 1.1; %rpm ,（乘上速度系数）
                RailMotorVel = abs(MovAchorVel ./ app.S_leadScrew *60) * 1.1; %rpm
                
                %驱动电机走第一段轨迹
                for i=1:N1
                    for ID=1:8
                        app.winchMotors(ID).setMotorMotion(WinchMotorPos1(ID,i), WinchMotorVel1(ID,i), true); %添加绞盘电机目标速度位置到CAN发送队列
                        app.railMotors(ID).setMotorMotion(RailMotorPos(ID,i), RailMotorVel(ID,i), true); %添加轨道电机目标速度位置到CAN发送队列
                    end
                    app.CAN.sendTransQueue(); %一次性发送命令帧序列（只写一次USB）
                    pause(t_Step); 
                end
                
                if norm(intervalPose - app.startPose)>0 %当过渡位姿不与起始点位姿重合时才规划第二段轨迹
                    %生成第二段轨迹
                    [pos2, ~, ~, eular2, ~, N2] = trajPlan2(intervalPose, app.startPose, vmax, amax, 0, t_Step);
                    %计算第二段轨迹绳长变化
                    CableTraj2=zeros(8,N2);
                    for i=1:N2
                        app.Platform = app.Platform.setPoseG([pos2(:,i); eular2(:, i)]);
                        app.calculateCable();
                        CableTraj2(1:end, i) = app.Cable.Length;
                    end
                    CableTraj2 = CableTraj2-repmat(app.Cable.OriginLength',1,N2); %计算锚点间绳长相对变化量
                    
                    %计算第二段轨迹绞盘电机转角和轨道电机转角
                    WinchMotorPos2 = CableTraj2 ./ app.R_winch; %rad
                    %计算第二段绞盘电机速度与轨道电机转速
                    WinchMotorVel2 = diff(WinchMotorPos2')./ t_Step; %rad/s,diff计算矩阵行之间的差分，此处转置
                    WinchMotorVel2 = cat(2, zeros(8,1), WinchMotorVel2'); %再转置，第一列补零
                    WinchMotorVel2 = abs(WinchMotorVel2 * 60 / (2*pi)) * 1.1; %rpm ,（乘上速度系数）
                    
                    %驱动电机走第二段轨迹
                    for i=1:N2
                        for ID=1:8
                            app.winchMotors(ID).setMotorMotion(WinchMotorPos2(ID,i), WinchMotorVel2(ID,i), true);
                        end
                        app.CAN.sendTransQueue(); %一次性发送命令帧序列（只写一次USB）
                        pause(t_Step); 
                    end
                end
                
                %障碍物运动到起点就位
                if ~strcmp(app.ObstacleIDDropDown.Value,'Add New ObsBB')
                    ID = str2double(app.ObstacleIDDropDown.Value);
                    if size(app.trajInfo.obsBBTraj,2)>=ID && size(app.trajInfo.obsBBTraj{ID},2)>=1
                        obsTraj = app.trajInfo.obsBBTraj{ID};
                        position = app.T_G2UR5 * [obsTraj(:,1); 1];
                        position = position(1:3);
                        position(3) = position(3) + app.obsBB(ID).height/2 + app.linkOffset_ObsBB; %add offset to TCP
                        targetPose = [position; app.TCP_CaliPose_RotVec(4:6)];
                        vel = 0.02; %m/s
                        accel = 0.01; %m/(s^2)
                        app.UR5.moveL_Trapez(targetPose, vel, accel);
                        
                        %更新障碍物位置
                        app.obsBB(ID) = app.obsBB(ID).setOriginPosition(obsTraj(:,1));
                    else
                        app.WarningTextArea.Value = 'Please generate trajectory for this obstacle first!';
                    end
                else
                    app.WarningTextArea.Value = 'Please choose an obstacle ID!';
                end
                
                %更新图像
                drawCDPR(app);
                displayObsBB(app);
            end
        end

        % Button pushed function: ResetInitAllModulesButton
        function ResetInitAllModulesButtonPushed(app, event)
            pause('on');
            for i=1:8
                app.winchMotors(i).resetDriver(true); %绞盘电机驱动器重置
                app.railMotors(i).resetDriver(true); %轨道电机驱动器重置
            end
            app.CAN.sendTransQueue();
            pause(0.6); %复位大约需要500ms
            
            for i=1:8
                app.winchMotors(i).setDriverMode(1, true); %绞盘电机驱动器速度-位置模式
                app.railMotors(i).setDriverMode(1, true); %轨道电机驱动器速度-位置模式
            end
            app.CAN.sendTransQueue();  
            pause(0.1);
            
            app.Platform = app.Platform.resetPoseG();
            app.MovAnchor = app.MovAnchor.resetAllPos();
            app.calculateCable();
            app.drawCDPR();
            app.displayObsBB();
            app.WarningTextArea.Value = "Please make sure that the end-effector platform is in original pose before clicking the next button!";
%             app.calculateCable(); %calculate current cable length between anchors
%             app.Cable.OriginLength = app.Cable.Length; %record current cable length as the original cable length
        end

        % Button pushed function: GoBackToOriginalPoseButton
        function GoBackToOriginalPoseButtonPushed(app, event)
            %障碍物先撤出工作区
            if ~strcmp(app.ObstacleIDDropDown.Value,'Add New ObsBB')
                ID = str2double(app.ObstacleIDDropDown.Value);
                position = app.T_G2UR5 * [0; 0; 600*1e-3; 1]; 
                position = position(1:3);
                position(3) = position(3) + app.obsBB(ID).height/2 + app.linkOffset_ObsBB; %add offset to TCP
                targetPose = [position; app.TCP_CaliPose_RotVec(4:6)];
                vel = 0.03; %m/s
                accel = 0.02; %m/(s^2)
                app.UR5.moveL_Trapez(targetPose, vel, accel);
            else
                app.WarningTextArea.Value = 'Please choose an obstacle ID to move it out of the workspace!';
            end
            
            
            pause('on');
            
            t_Step = 0.1; %s
            vmax = 0.05; %m/s
            amax = 0.025; %m/(s^2)
            
            %% 末端回位
            intervalPose = app.Platform.originPose_G;
            intervalPose(3) = app.Platform.pose_G(3); %中间姿态（只取当前点Z轴的坐标量）
            
            if norm(intervalPose - app.Platform.pose_G)>0 %当过渡位姿与当前位姿不一致时才规划第一段轨迹
                %生成第一段轨迹
                [pos1, ~, ~, eular1, ~, N1] = trajPlan2(app.Platform.pose_G, intervalPose, vmax, amax, 0, t_Step); 
                
                %计算第一段轨迹绳长变化
                CableTraj1=zeros(8,N1);
                for i=1:N1
                    app.Platform = app.Platform.setPoseG([pos1(:,i); eular1(:, i)]);
                    
                    app.calculateCable();
                    CableTraj1(1:end, i) = app.Cable.Length + app.MovAnchor.positon_L;
                end
                CableTraj1 = CableTraj1-repmat(app.Cable.OriginLength',1,N1); %计算锚点间绳长相对变化量
                
                %计算第一段轨迹绞盘电机转角
                WinchMotorPos1 = CableTraj1 ./ app.R_winch; %rad
                
                %计算第一段绞盘电机速度
                WinchMotorVel1 = diff(WinchMotorPos1')./ t_Step; %rad/s,diff计算矩阵行之间的差分，此处转置
                WinchMotorVel1 = cat(2, zeros(8,1), WinchMotorVel1'); %再转置，第一列补零
                WinchMotorVel1 = abs(WinchMotorVel1 * 60 / (2*pi)) * 1.1; %rpm ,（乘上速度系数）
                
                %驱动电机走第一段轨迹
                for i=1:N1
                    for ID=1:8
                        app.winchMotors(ID).setMotorMotion(WinchMotorPos1(ID,i), WinchMotorVel1(ID,i), true); %添加绞盘电机目标速度位置到CAN发送队列
                    end
                    app.CAN.sendTransQueue(); %一次性发送命令帧序列（只写一次USB）
                    pause(t_Step); 
                end
            end
            
            %生成第二段轨迹 + 动锚点座返回原位轨迹
            [pos2, ~, ~, eular2, ~, N2] = trajPlan1(intervalPose, app.Platform.originPose_G, 0, 15, t_Step); %与动锚点同步运动时间设为15秒
            % 规划动锚点
            originPosition = app.MovAnchor.positon_L;
            targetPosition = zeros(1,8); % 初始局部位置
            MovAchorPos = zeros(8,N2); %预定义
            MovAchorVel = zeros(8,N2); %预定义
            for anchorID=1:8
                [RailPos, RailVel, ~, ~, ~, ~] = trajPlan1([originPosition(anchorID);0;0;0;0;0], [targetPosition(anchorID);0;0;0;0;0],...
                                                             0, 15, t_Step); %与轨迹2同步运动时间设为15秒
                MovAchorPos(anchorID,:) = RailPos(1,:); MovAchorVel(anchorID,:) = RailVel(1,:); %记录8个锚点座位置和速度
            end
            %计算第二段轨迹绳长变化
            CableTraj2=zeros(8,N2);
            for i=1:N2
                app.Platform = app.Platform.setPoseG([pos2(:,i); eular2(:, i)]);
                app.MovAnchor = app.MovAnchor.setPosL(MovAchorPos(:,i)'); %更新锚点座位置
                app.calculateCable();
                CableTraj2(1:end, i) = app.Cable.Length;
            end
            CableTraj2 = CableTraj2-repmat(app.Cable.OriginLength',1,N2); %计算锚点间绳长相对变化量
            %计算第二段轨迹绞盘电机转角和轨道电机转角
            WinchMotorPos2 = CableTraj2 ./ app.R_winch; %rad
            RailMotorPos = MovAchorPos ./ app.S_leadScrew * 2*pi; %rad
            %计算第二段绞盘电机速度与轨道电机转速
            WinchMotorVel2 = diff(WinchMotorPos2')./ t_Step; %rad/s,diff计算矩阵行之间的差分，此处转置
            WinchMotorVel2 = cat(2, zeros(8,1), WinchMotorVel2'); %再转置，第一列补零
            WinchMotorVel2 = abs(WinchMotorVel2 * 60 / (2*pi)) * 1.1; %rpm ,（乘上速度系数）
            RailMotorVel = abs(MovAchorVel ./ app.S_leadScrew *60) * 1.1; %rpm
            %驱动电机走第二段轨迹
            for i=1:N2
                for ID=1:8
                    app.winchMotors(ID).setMotorMotion(WinchMotorPos2(ID,i), WinchMotorVel2(ID,i), true);
                    app.railMotors(ID).setMotorMotion(RailMotorPos(ID,i), RailMotorVel(ID,i), true); %添加轨道电机目标速度位置到CAN发送队列
                end
                app.CAN.sendTransQueue(); %一次性发送命令帧序列（只写一次USB）
                pause(t_Step); 
            end

            %更新图像
            drawCDPR(app);
            displayObsBB(app);
        end

        % Value changed function: ModeDropDown_2
        function ModeDropDown_2ValueChanged(app, event)
            value = app.ModeDropDown_2.Value;
            switch value
                case '0'
                    app.specifypathforendeffectorButton.Visible = 'on';
                    app.AlgorithmDropDown.Items = {'Optimization Criterion: Force Distribution',...
                                                   'Optimization Criterion: Stiffness in Z-Direction',...
                                                   'Optimization Criterion: Movable Anchor Displacement'};
                    app.AlgorithmDropDown.ItemsData = {'0','1','2'};
                    app.AlgorithmDropDown.Value = '0';
                case '1'
                    app.specifypathforendeffectorButton.Visible = 'off';
                    app.AlgorithmDropDown.Items = {'RRT*',...
                                                   'Fuzzy Decision + RRT*'};
                    app.AlgorithmDropDown.ItemsData = {'0','1'};
                    app.AlgorithmDropDown.Value = '0';
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if strcmp(app.AddButton.Text, 'Add') %Add
                app.obsNumber = app.obsNumber + 1;
                app.ObstacleIDDropDown.Items(end + 1) = app.ObstacleIDDropDown.Items(end);
                app.ObstacleIDDropDown.Items{end - 1} = num2str(app.obsNumber); %update ObstacleIDDropDown.Items
                app.ObstacleIDDropDown.Value = app.ObstacleIDDropDown.Items{end - 1}; %update ObstacleIDDropDown.Value
                app.AddButton.Text = 'Delete';
                
                length = app.lengthmmEditField.Value * 1e-3;
                width = app.widthmmEditField.Value * 1e-3;
                height = app.heightmmEditField.Value * 1e-3;
                
                x = app.XmmSpinner.Value*1e-3; y = app.YmmSpinner.Value*1e-3; z = app.ZmmSpinner.Value*1e-3; 
                origin = [x,y,z]';
                
                theta1 = app.DirectionAngle1Spinner.Value*pi/180;
                theta2 = app.DirectionAngle2Spinner.Value*pi/180;
                directionVector = [sin(theta1)*cos(theta2); sin(theta1)*sin(theta2); cos(theta1)];
                velocity = app.SpeedOfObstacleEditField.Value * directionVector;
                acceleration = app.AccelerationOfObstacleEditField.Value;
                
                app.obsBB(app.obsNumber) = ObsBB(length, width, height, origin, velocity, ...
                                            acceleration, app.default_dmin, app.default_ds); %generate new obstacle
                app.displayObsBB();
                
                app.minDisToCableButton.Visible = 'on';
                app.minDisToCableButton.Enable = 'on';
                app.GenerateObstacleTrajectoryButton.Visible = 'on';
                app.GenerateObstacleTrajectoryButton.Enable = 'on';
            else %Delete
                app.AddButton.Text = 'Add';
                index = 1:app.obsNumber;
                app.obsNumber = app.obsNumber - 1;
                ID = str2double(app.ObstacleIDDropDown.Value);
                index = find(index~=ID);               
                
                app.ObstacleIDDropDown.Items = {};
                if size(index, 2) ~= 0
                    app.obsBB = app.obsBB(index);
                    for i=1:app.obsNumber
                        app.ObstacleIDDropDown.Items{i}=num2str(i);
                    end
                    app.ObstacleIDDropDown.Items{end+1} = 'Add New ObsBB';
                else
                    app.obsBB = [];
                    app.ObstacleIDDropDown.Items{1} = 'Add New ObsBB';
                end
                
                app.ObstacleIDDropDown.Value = 'Add New ObsBB';
                app.drawCDPR();
                app.displayObsBB();
                
                app.minDisToCableButton.Visible = 'off';
                app.minDisToCableButton.Enable = 'off';
                app.GenerateObstacleTrajectoryButton.Visible = 'off';
                app.GenerateObstacleTrajectoryButton.Enable = 'off';
            end
        end

        % Value changed function: XmmSpinner
        function XmmSpinnerValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB') 
                X = app.XmmSpinner.Value*1e-3;
                ID = str2double(app.ObstacleIDDropDown.Value);
                origin = app.obsBB(ID).origin;
                origin(1) = X;
                app.obsBB(ID) = app.obsBB(ID).setOriginPosition(origin);
                
                app.drawCDPR();
                app.displayObsBB();
            end 
        end

        % Value changed function: YmmSpinner
        function YmmSpinnerValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB') 
                Y = app.YmmSpinner.Value*1e-3;
                ID = str2double(app.ObstacleIDDropDown.Value);
                origin = app.obsBB(ID).origin;
                origin(2) = Y;
                app.obsBB(ID) = app.obsBB(ID).setOriginPosition(origin);
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Value changed function: ZmmSpinner
        function ZmmSpinnerValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB') 
                Z = app.ZmmSpinner.Value*1e-3;
                ID = str2double(app.ObstacleIDDropDown.Value);
                origin = app.obsBB(ID).origin;
                origin(3) = Z;
                app.obsBB(ID) = app.obsBB(ID).setOriginPosition(origin);
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Value changed function: SpeedOfObstacleEditField
        function SpeedOfObstacleEditFieldValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB')
                ID = str2double(app.ObstacleIDDropDown.Value);
                
                theta1 = app.DirectionAngle1Spinner.Value*pi/180;
                theta2 = app.DirectionAngle2Spinner.Value*pi/180;
                directionVector = [sin(theta1)*cos(theta2); sin(theta1)*sin(theta2); cos(theta1)];
                
                velocity = app.SpeedOfObstacleEditField.Value * directionVector;
                app.obsBB(ID).velocity = velocity;
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Value changed function: AccelerationOfObstacleEditField
        function AccelerationOfObstacleEditFieldValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB')
                value = app.AccelerationOfObstacleEditField.Value;
                ID = str2double(app.ObstacleIDDropDown.Value);
                
                app.obsBB(ID).acceleration = value;
            end
        end

        % Value changed function: DirectionAngle1Spinner
        function DirectionAngle1SpinnerValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB')
                ID = str2double(app.ObstacleIDDropDown.Value);
                
                theta1 = app.DirectionAngle1Spinner.Value*pi/180;
                theta2 = app.DirectionAngle2Spinner.Value*pi/180;
                directionVector = [sin(theta1)*cos(theta2); sin(theta1)*sin(theta2); cos(theta1)];
                
                velocity = app.SpeedOfObstacleEditField.Value * directionVector;
                app.obsBB(ID).velocity = velocity;
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Value changed function: DirectionAngle2Spinner
        function DirectionAngle2SpinnerValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB')
                ID = str2double(app.ObstacleIDDropDown.Value);
                
                theta1 = app.DirectionAngle1Spinner.Value*pi/180;
                theta2 = app.DirectionAngle2Spinner.Value*pi/180;
                directionVector = [sin(theta1)*cos(theta2); sin(theta1)*sin(theta2); cos(theta1)];
                
                velocity = app.SpeedOfObstacleEditField.Value * directionVector;
                app.obsBB(ID).velocity = velocity;
                
                app.drawCDPR();
                app.displayObsBB();
            end
        end

        % Value changed function: ObstacleIDDropDown
        function ObstacleIDDropDownValueChanged(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value, 'Add New ObsBB')
                ID = str2double(app.ObstacleIDDropDown.Value);
                app.lengthmmEditField.Value = app.obsBB(ID).length*1e3;
                app.widthmmEditField.Value = app.obsBB(ID).width*1e3;
                app.heightmmEditField.Value = app.obsBB(ID).height*1e3;
                app.XmmSpinner.Value = app.obsBB(ID).origin(1)*1e3;
                app.YmmSpinner.Value = app.obsBB(ID).origin(2)*1e3;
                app.ZmmSpinner.Value = app.obsBB(ID).origin(3)*1e3;
                app.SpeedOfObstacleEditField.Value = norm(app.obsBB(ID).velocity);
                
                if app.SpeedOfObstacleEditField.Value >= 1e-6
                    directionVector = app.obsBB(ID).velocity / app.SpeedOfObstacleEditField.Value;
                else
                    directionVector = [0;0;0];
                end
                app.DirectionAngle1Spinner.Value = acosd(directionVector(3));
                app.DirectionAngle2Spinner.Value = acosd(directionVector(1) / sind(app.DirectionAngle1Spinner.Value));
                
                app.AccelerationOfObstacleEditField.Value = app.obsBB(ID).acceleration;
                app.AddButton.Text = 'Delete';
                
                app.minDisToCableButton.Visible = 'on';
                app.minDisToCableButton.Enable = 'on';
                app.GenerateObstacleTrajectoryButton.Visible = 'on';
                app.GenerateObstacleTrajectoryButton.Enable = 'on';
            else
                app.AddButton.Text = 'Add';
                app.minDisToCableButton.Visible = 'off';
                app.minDisToCableButton.Enable = 'off';
                app.GenerateObstacleTrajectoryButton.Visible = 'off';
                app.GenerateObstacleTrajectoryButton.Enable = 'off';
            end
        end

        % Button pushed function: minDisToCableButton
        function minDisToCableButtonPushed(app, event)
            cableObsDis = zeros(1,8);
            ObsID = str2double(app.ObstacleIDDropDown.Value); 
            for i=1:8
                line = [app.Platform.anchorPosition_G(:,i), app.Cable.TanLinVector(:,i)+app.Platform.anchorPosition_G(:,i)];
                cableObsDis(i) = openGJK(line,app.obsBB(ObsID).vertices);
            end
            [minDis, index] = min(cableObsDis);
            
            s1 = sprintf("ObsBB %d mininum Distance to Cable:\n%f mm\n", ObsID, minDis*1e3);
            s2 = sprintf("\nClosest Cable ID:\n%d", index);
            app.TrajectoryInfoTextArea.Value = strcat(s1,s2);
        end

        % Value changed function: PlanningtimestepEditField
        function PlanningtimestepEditFieldValueChanged(app, event)
            value = app.PlanningtimestepEditField.Value;
            app.timestepEditField.Value = value;
            app.timeStep = app.timestepEditField.Value;
            app.trajInfo.timeStep = app.timeStep;
        end

        % Button pushed function: PlotEEStiffnessGraphButton
        function PlotEEStiffnessGraphButtonPushed(app, event)
            if app.ModeDropDown_2.Value == '0' && app.AlgorithmDropDown.Value == '2' && size(app.trajInfo.Stiffness, 2)>=1
                f=figure('Name','Stiffness Variation Graph','NumberTitle','off');   
                subplot(2,1,1);
                dispLengend1 = {'X-Translation', 'Y-Translation', 'Z-Translation'};
                for i=1:3
                    plot(app.trajInfo.time, app.trajInfo.Stiffness(i,:));
                    hold on;
                end
                legend(dispLengend1,"FontSize",12);
                xlabel('time(s)', "FontSize", 15);
                ylabel('stiffness(N/m)', "FontSize", 15);
                title("End-effector Stiffness On Translational Principal Axis", "FontSize", 15);
                
                subplot(2,1,2);
                dispLengend2 = {'X-Rotation', 'Y-Rotation', 'Z-Rotation'};
                for i=4:6
                    plot(app.trajInfo.time, app.trajInfo.Stiffness(i,:));
                    hold on;
                end
                legend(dispLengend2,"FontSize",12);
                xlabel('time(s)', "FontSize", 15);
                ylabel('stiffness(N*m/rad)', "FontSize", 15);
                title("End-effector Stiffness On Rotational Principal Axis", "FontSize", 15);
            else
                app.WarningTextArea.Value = 'please choose Mode 1, algorithm 2 to generate result and plot stiffness graph!';
            end
        end

        % Button pushed function: ResetPositionButton_2
        function ResetPositionButton_2Pushed(app, event)
            pause('on');
            anchorID = app.AnchorIDEditField.Value;
            %5-order linear planning
            originPosition = app.MovAnchor.positon_L;
            targetPosition = zeros(1,8); % original local position
            [RailPos, RailVel, ~, ~, ~, N] = trajPlan2([originPosition(anchorID);0;0;0;0;0], [targetPosition(anchorID);0;0;0;0;0],...
                                            app.MaxSpeedEditField.Value, app.MaxAccelEditField.Value, 0, 0.1);
                                        
            RailPos = RailPos(1,:); RailVel = RailVel(1,:);
            
            %计算绳长变化
            CableTraj=zeros(1,N);
            pos_Now = originPosition;
            for i=1:N
                pos_Now(anchorID) = RailPos(i);
                app.MovAnchor = app.MovAnchor.setPosL(pos_Now);
                app.calculateCable();
                CableTraj(i) = app.Cable.Length(anchorID);
            end
            CableTraj = CableTraj - repmat(app.Cable.OriginLength(anchorID),1,N); %计算锚点间绳长相对变化量
            %计算绞盘电机转角
            CableChange = CableTraj + RailPos;
            WinchMotorPos = CableChange ./ app.R_winch; %rad
            %计算绞盘电机速度
            WinchMotorVel = diff(WinchMotorPos)./0.1; %rad/s
            WinchMotorVel = cat(2, 0, WinchMotorVel);
            WinchMotorVel = abs(WinchMotorVel * 60 / (2*pi)); %rpm
            %计算轨道电机转角和速度
            RailMotorPos = RailPos ./ app.S_leadScrew * 2*pi; %rad
            RailMotorVel = abs(RailVel ./ app.S_leadScrew *60); %rpm
            
            for i=1:N
                app.winchMotors(anchorID).setMotorMotion(WinchMotorPos(i), WinchMotorVel(i)*1.2, true); %添加绞盘电机目标速度位置到CAN发送队列
                app.railMotors(anchorID).setMotorMotion(RailMotorPos(i), RailMotorVel(i)*1.2, true); %添加轨道电机目标速度位置到CAN发送队列
                app.CAN.sendTransQueue(); %发送并重置CAN队列
                pause(0.1); 
            end
        end

        % Button pushed function: StartTestingSimulationResultButton
        function StartTestingSimulationResultButtonPushed(app, event)
            if size(app.trajInfo.winchMotorsPos, 2)>0 && size(app.trajInfo.railMotorsPos, 2)>0 %当规划好了电机轨迹时
                if norm(app.Platform.pose_G - app.startPose)==0 && norm(app.MovAnchor.positon_L)==0 %当前位置与起始点是否重合，动锚点是否归位
                    
                    %障碍物运动
                    if ~strcmp(app.ObstacleIDDropDown.Value,'Add New ObsBB')
                        ID = str2double(app.ObstacleIDDropDown.Value);
                        if norm(app.obsBB(ID).velocity)>0 && size(app.trajInfo.obsBBTraj,2)>=ID && size(app.trajInfo.obsBBTraj{ID},2)>1
                            obsTraj = app.trajInfo.obsBBTraj{ID};
                            position = app.T_G2UR5 * [obsTraj(:,end); 1];
                            position = position(1:3);
                            position(3) = position(3) + app.obsBB(ID).height/2 + app.linkOffset_ObsBB; %add offset to TCP
                            targetPose = [position; app.TCP_CaliPose_RotVec(4:6)];
                            vel = norm(app.obsBB(ID).velocity); %m/s
                            accel = app.obsBB(ID).acceleration; %m/(s^2)
                            app.UR5.moveL_Trapez(targetPose, vel, accel);
                            
                            % 障碍物位置设为终点位
                            app.obsBB(ID) = app.obsBB(ID).setOriginPosition(obsTraj(:,end));
                        else
                            app.WarningTextArea.Value = 'This obstacle is static,or no trajectory generated';
                        end
                    else
                        app.WarningTextArea.Value = 'Please choose an obstacle ID!';
                    end
            
                    %CDPR机器人运动
                    for i=2:app.trajInfo.Number
                        for ID=1:8
                            app.winchMotors(ID).setMotorMotion(app.trajInfo.winchMotorsPos(ID,i), app.trajInfo.winchMotorsSpeed(ID,i)*1.1, true); %添加绞盘电机目标速度位置到CAN发送队列
                            app.railMotors(ID).setMotorMotion(app.trajInfo.railMotorsPos(ID,i), app.trajInfo.railMotorsSpeed(ID,i)*1.1, true); %添加轨道电机目标速度位置到CAN发送队列
                        end
                        app.CAN.sendTransQueue(); %发送并重置CAN队列
                        pause(app.trajInfo.timeStep);
                    end
                    
                    %更新图像
                    app.Platform = app.Platform.setPoseG([app.trajInfo.platformTraj.pos(:,end); app.trajInfo.platformTraj.eular(:,end)]);
                    app.MovAnchor = app.MovAnchor.setPosL(app.trajInfo.anchorTraj.pos(:,end));
                    app.calculateCable();
                    app.drawCDPR();
                    app.displayObsBB();
                else
                    app.WarningTextArea.Value = "Please Make Sure You've Clicked the go-to-start-pose Button!";
                end
            end
        end

        % Value changed function: MaxTensionEditField
        function MaxTensionEditFieldValueChanged(app, event)
            value = app.MaxTensionEditField.Value;
            app.trajInfo.wrenchSetting.feaspTensionRange(2) = value;
        end

        % Value changed function: MinTensionEditField
        function MinTensionEditFieldValueChanged(app, event)
            value = app.MinTensionEditField.Value;
            app.trajInfo.wrenchSetting.feaspTensionRange(1) = value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.trajInfo.wrenchSetting.wrench = app.UITable.Data';
        end

        % Button pushed function: ConnectUR5Button
        function ConnectUR5ButtonPushed(app, event)
            if strcmp(app.ConnectUR5Button.Text, 'Connect UR5') 
                app.UR5.IP = app.URIPAdressEditField.Value;
                app.UR5.ConnectUR5();
                app.ConnectUR5Button.Text = 'Disconnect UR5';
            else
                app.UR5.DisconnectUR5();
                app.ConnectUR5Button.Text = 'Connect UR5';
            end
        end

        % Button pushed function: TestObstacleTrajectoryButton
        function TestObstacleTrajectoryButtonPushed(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value,'Add New ObsBB')
                ID = str2double(app.ObstacleIDDropDown.Value);
                if norm(app.obsBB(ID).velocity)>0 && size(app.trajInfo.obsBBTraj,2)>=ID && size(app.trajInfo.obsBBTraj{ID},2)>1
                    obsTraj = app.trajInfo.obsBBTraj{ID};
                    position = app.T_G2UR5 * [obsTraj(:,end); 1];
                    position = position(1:3);
                    position(3) = position(3) + app.obsBB(ID).height/2 + app.linkOffset_ObsBB; %add offset to TCP
                    targetPose = [position; app.TCP_CaliPose_RotVec(4:6)];
                    vel = norm(app.obsBB(ID).velocity); %m/s
                    accel = app.obsBB(ID).acceleration; %m/(s^2)
                    app.UR5.moveL_Trapez(targetPose, vel, accel);
                    
                    % 动画演示障碍物轨迹后，重新回到原位
                    if vel ~= 0
                        pauseTime = app.trajInfo.timeStep*0.1;
                        for i=1:size(obsTraj,2)
                           app.obsBB(ID) = app.obsBB(ID).setOriginPosition(obsTraj(:,i));
                            app.drawCDPR();
                            app.displayObsBB();
                            
                            pause(pauseTime);
                        end
                    end
                else
                    app.WarningTextArea.Value = 'This obstacle is static,or no trajectory generated';
                end
            else
                app.WarningTextArea.Value = 'Please choose an obstacle ID!';
            end
        end

        % Button pushed function: ResetObstaclePositionButton
        function ResetObstaclePositionButtonPushed(app, event)
            if ~strcmp(app.ObstacleIDDropDown.Value,'Add New ObsBB')
                ID = str2double(app.ObstacleIDDropDown.Value);
                if size(app.trajInfo.obsBBTraj,2)>=ID && size(app.trajInfo.obsBBTraj{ID},2)>=1
                    obsTraj = app.trajInfo.obsBBTraj{ID};
                    position = app.T_G2UR5 * [obsTraj(:,1); 1];
                    position = position(1:3);
                    position(3) = position(3) + app.obsBB(ID).height/2 + app.linkOffset_ObsBB; %add offset to TCP
                    targetPose = [position; app.TCP_CaliPose_RotVec(4:6)];
                    vel = 0.02; %m/s
                    accel = 0.01; %m/(s^2)
                    app.UR5.moveL_Trapez(targetPose, vel, accel);
                    
                    %更新图像
                    app.obsBB(ID) = app.obsBB(ID).setOriginPosition(obsTraj(:,1));
                    app.drawCDPR();
                    app.displayObsBB();
                else
                    app.WarningTextArea.Value = 'Please generate trajectory for this obstacle first!';
                end
            else
                app.WarningTextArea.Value = 'Please choose an obstacle ID!';
            end
        end

        % Button pushed function: PlotAnchorTrajectoryGraphButton
        function PlotAnchorTrajectoryGraphButtonPushed(app, event)
            f1=figure('Name','Anchor Trajectory Graph','NumberTitle','off');
            axe1 = axes(f1);
            dispLengend = cell(1,8);
            for i=1:8
                dispLengend{i} = strcat("Anchor ", num2str(i));
                plot(axe1, app.trajInfo.time, app.trajInfo.anchorTraj.pos(i,:));
                hold on;
            end
            legend(axe1,dispLengend,"FontSize",14);
            xlabel(axe1, 'time(s)', "FontSize", 15);
            ylabel(axe1, 'local position(m)', "FontSize", 15);
            title(axe1,"Anchor Trajectory Graph", "FontSize", 15);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {789, 789, 789};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {789, 789};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {327, '1x', 621};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.endtimeEditFieldLabel = uilabel(app.LeftPanel);
            app.endtimeEditFieldLabel.Text = 'end time  ';
            app.endtimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.endtimeEditField.ValueChangedFcn = createCallbackFcn(app, @endtimeEditFieldValueChanged, true);
            app.endtimeEditField.Value = 5;
            app.timestepEditFieldLabel = uilabel(app.LeftPanel);
            app.timestepEditFieldLabel.Text = 'time step  ';
            app.timestepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.timestepEditField.ValueChangedFcn = createCallbackFcn(app, @timestepEditFieldValueChanged, true);
            app.timestepEditField.Value = 0.1;
            app.generateButton = uibutton(app.LeftPanel, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'generate';
            app.TrajectoryInfoTextAreaLabel = uilabel(app.LeftPanel);
            app.TrajectoryInfoTextAreaLabel.Text = 'Trajectory Info';
            app.TrajectoryInfoTextArea = uitextarea(app.LeftPanel);
            app.starttimeEditFieldLabel = uilabel(app.LeftPanel);
            app.starttimeEditFieldLabel.Text = 'start time ';
            app.starttimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.starttimeEditField.ValueChangedFcn = createCallbackFcn(app, @starttimeEditFieldValueChanged, true);
            app.startposeandpositionLabel = uilabel(app.LeftPanel);
            app.startposeandpositionLabel.Text = 'start pose and position';
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField_3Label = uilabel(app.LeftPanel);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.mmLabel = uilabel(app.LeftPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.LeftPanel);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.LeftPanel);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_4 = uilabel(app.LeftPanel);
            app.mmLabel_4.Text = 'mm';
            app.ZEditFieldLabel = uilabel(app.LeftPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = 37;
            app.YEditField_2Label = uilabel(app.LeftPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.mmLabel_5 = uilabel(app.LeftPanel);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.LeftPanel);
            app.mmLabel_6.Text = 'mm';
            app.ZEditField_2Label = uilabel(app.LeftPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.ZEditField_2.Value = 37;
            app.endposeandpositionLabel = uilabel(app.LeftPanel);
            app.endposeandpositionLabel.Text = 'end pose and position';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '°';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '°';
            app.bEditField_2Label = uilabel(app.LeftPanel);
            app.bEditField_2Label.Text = 'b';
            app.bEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField_2.ValueChangedFcn = createCallbackFcn(app, @bEditField_2ValueChanged, true);
            app.yEditField_2Label = uilabel(app.LeftPanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.yEditField_2.ValueChangedFcn = createCallbackFcn(app, @yEditField_2ValueChanged, true);
            app.aEditField_2Label = uilabel(app.LeftPanel);
            app.aEditField_2Label.Text = 'a';
            app.aEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField_2.ValueChangedFcn = createCallbackFcn(app, @aEditField_2ValueChanged, true);
            app.sLabel = uilabel(app.LeftPanel);
            app.sLabel.Text = 's  ';
            app.sLabel_2 = uilabel(app.LeftPanel);
            app.sLabel_2.Text = 's ';
            app.sLabel_3 = uilabel(app.LeftPanel);
            app.sLabel_3.Text = 's';
            app.WarningTextAreaLabel = uilabel(app.LeftPanel);
            app.WarningTextAreaLabel.Text = 'Warning';
            app.WarningTextArea = uitextarea(app.LeftPanel);
            app.MaxaEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxaEditFieldLabel.Visible = 'off';
            app.MaxaEditFieldLabel.Text = 'Max a';
            app.MaxaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxaEditField.ValueChangedFcn = createCallbackFcn(app, @MaxaEditFieldValueChanged, true);
            app.MaxaEditField.Visible = 'off';
            app.MaxaEditField.Value = 0.2;
            app.ms2Label = uilabel(app.LeftPanel);
            app.ms2Label.Visible = 'off';
            app.ms2Label.Text = 'm/(s^2)';
            app.ModeDropDownLabel = uilabel(app.LeftPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.LeftPanel);
            app.ModeDropDown.Items = {'specify time', 'specify max speed and acceleration'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'specify time';
            app.MaxvEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxvEditFieldLabel.Visible = 'off';
            app.MaxvEditFieldLabel.Text = 'Max v';
            app.MaxvEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxvEditField.ValueChangedFcn = createCallbackFcn(app, @MaxvEditFieldValueChanged, true);
            app.MaxvEditField.Visible = 'off';
            app.MaxvEditField.Value = 0.4;
            app.ResetPositionButton = uibutton(app.LeftPanel, 'push');
            app.ResetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPositionButtonPushed, true);
            app.ResetPositionButton.Text = 'Reset Position';
            app.showobstaclesgeneratedCheckBox = uicheckbox(app.LeftPanel);
            app.showobstaclesgeneratedCheckBox.ValueChangedFcn = createCallbackFcn(app, @showobstaclesgeneratedCheckBoxValueChanged, true);
            app.showobstaclesgeneratedCheckBox.Text = 'show obstacles generated';
            app.showobstaclesgeneratedCheckBox.Value = true;
            app.updatestartposeButton = uibutton(app.LeftPanel, 'push');
            app.updatestartposeButton.ButtonPushedFcn = createCallbackFcn(app, @updatestartposeButtonPushed, true);
            app.updatestartposeButton.Text = 'update start pose';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '3D model of CDPR')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.azimuthSliderLabel = uilabel(app.CenterPanel);
            app.azimuthSliderLabel.Text = 'azimuth ';
            app.azimuthSlider = uislider(app.CenterPanel);
            app.azimuthSlider.Limits = [-180 180];
            app.azimuthSlider.ValueChangedFcn = createCallbackFcn(app, @azimuthSliderValueChanged, true);
            app.azimuthSlider.ValueChangingFcn = createCallbackFcn(app, @azimuthSliderValueChanging, true);
            app.azimuthSlider.Value = -37.5;
            app.elevationSliderLabel = uilabel(app.CenterPanel);
            app.elevationSliderLabel.Text = 'elevation ';
            app.elevationSlider = uislider(app.CenterPanel);
            app.elevationSlider.Limits = [-180 180];
            app.elevationSlider.ValueChangedFcn = createCallbackFcn(app, @elevationSliderValueChanged, true);
            app.elevationSlider.ValueChangingFcn = createCallbackFcn(app, @elevationSliderValueChanging, true);
            app.elevationSlider.Value = 30;
            app.ResetViewButton = uibutton(app.CenterPanel, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.AdjustViewOf3DGraphLabel = uilabel(app.CenterPanel);
            app.AdjustViewOf3DGraphLabel.Text = 'Adjust View Of 3D Graph';
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Fx'; 'Fy'; 'Fz'; 'Mx'; 'My'; 'Mz'};
            app.UITable.RowName = {'Wrench'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.MaxTensionEditFieldLabel = uilabel(app.CenterPanel);
            app.MaxTensionEditFieldLabel.Text = 'Max Tension';
            app.MaxTensionEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MaxTensionEditField.Limits = [0 200];
            app.MaxTensionEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTensionEditFieldValueChanged, true);
            app.MaxTensionEditField.Value = 190;
            app.MinTensionEditFieldLabel = uilabel(app.CenterPanel);
            app.MinTensionEditFieldLabel.Text = 'Min Tension';
            app.MinTensionEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MinTensionEditField.Limits = [0 200];
            app.MinTensionEditField.ValueChangedFcn = createCallbackFcn(app, @MinTensionEditFieldValueChanged, true);
            app.MinTensionEditField.Value = 10;
            app.WrenchSettingLabel = uilabel(app.CenterPanel);
            app.WrenchSettingLabel.Text = {'Wrench '; 'Setting'};
            app.NLabel_2 = uilabel(app.CenterPanel);
            app.NLabel_2.Text = 'N';
            app.NLabel = uilabel(app.CenterPanel);
            app.NLabel.Text = 'N';
            app.RightPanel = uipanel(app.GridLayout);
            app.obstacleavoidancesimulationPanel = uipanel(app.RightPanel);
            app.obstacleavoidancesimulationPanel.Title = 'obstacle avoidance simulation';
            app.GenerateTuneobstaclesLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.GenerateTuneobstaclesLabel.Text = 'Generate & Tune obstacles';
            app.lengthmmEditFieldLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.lengthmmEditFieldLabel.Text = 'length/mm';
            app.lengthmmEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.lengthmmEditField.Limits = [0 250];
            app.lengthmmEditField.RoundFractionalValues = 'on';
            app.lengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @lengthmmEditFieldValueChanged, true);
            app.lengthmmEditField.Value = 150;
            app.widthmmEditFieldLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.widthmmEditFieldLabel.Text = 'width/mm';
            app.widthmmEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.widthmmEditField.Limits = [0 250];
            app.widthmmEditField.RoundFractionalValues = 'on';
            app.widthmmEditField.ValueChangedFcn = createCallbackFcn(app, @widthmmEditFieldValueChanged, true);
            app.widthmmEditField.Value = 150;
            app.heightmmEditFieldLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.heightmmEditFieldLabel.Text = 'height/mm';
            app.heightmmEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.heightmmEditField.Limits = [0 250];
            app.heightmmEditField.RoundFractionalValues = 'on';
            app.heightmmEditField.ValueChangedFcn = createCallbackFcn(app, @heightmmEditFieldValueChanged, true);
            app.heightmmEditField.Value = 150;
            app.GenerateObstacleTrajectoryButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.GenerateObstacleTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateObstacleTrajectoryButtonPushed, true);
            app.GenerateObstacleTrajectoryButton.Enable = 'off';
            app.GenerateObstacleTrajectoryButton.Visible = 'off';
            app.GenerateObstacleTrajectoryButton.Text = 'Generate Obstacle Trajectory';
            app.clearobstaclesButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.clearobstaclesButton.ButtonPushedFcn = createCallbackFcn(app, @clearobstaclesButtonPushed, true);
            app.clearobstaclesButton.Text = 'clear obstacles';
            app.PlanningConfigurationsLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.PlanningConfigurationsLabel.Text = 'Planning Configurations';
            app.ModeDropDown_2Label = uilabel(app.obstacleavoidancesimulationPanel);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown_2 = uidropdown(app.obstacleavoidancesimulationPanel);
            app.ModeDropDown_2.Items = {'End-effector Path Specified', 'End-effector Path Unknown'};
            app.ModeDropDown_2.ItemsData = {'0', '1'};
            app.ModeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_2ValueChanged, true);
            app.ModeDropDown_2.Value = '0';
            app.OptimizationFreqLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.OptimizationFreqLabel.Enable = 'off';
            app.OptimizationFreqLabel.Text = {'Optimization '; 'Freq'};
            app.OptimizationFreqEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.OptimizationFreqEditField.Limits = [1 50];
            app.OptimizationFreqEditField.ValueChangedFcn = createCallbackFcn(app, @OptimizationFreqEditFieldValueChanged, true);
            app.OptimizationFreqEditField.Enable = 'off';
            app.OptimizationFreqEditField.Value = 1;
            app.maxanchorstepLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.maxanchorstepLabel.Text = {'max anchor'; ' step'};
            app.maxanchorstepEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.maxanchorstepEditField.Limits = [0 Inf];
            app.maxanchorstepEditField.ValueChangedFcn = createCallbackFcn(app, @maxanchorstepEditFieldValueChanged, true);
            app.maxanchorstepEditField.Value = 0.02;
            app.maxanchorspeedLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.maxanchorspeedLabel.Text = {'max anchor '; 'speed'};
            app.maxanchorspeedEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.maxanchorspeedEditField.Limits = [0 Inf];
            app.maxanchorspeedEditField.ValueChangedFcn = createCallbackFcn(app, @maxanchorspeedEditFieldValueChanged, true);
            app.maxanchorspeedEditField.Value = 0.02;
            app.specifypathforendeffectorButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.specifypathforendeffectorButton.ButtonPushedFcn = createCallbackFcn(app, @specifypathforendeffectorButtonPushed, true);
            app.specifypathforendeffectorButton.Text = 'specify path for end-effector';
            app.mLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.mLabel.Text = 'm';
            app.msLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.msLabel.Text = 'm/s';
            app.generatesimulationresultButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.generatesimulationresultButton.ButtonPushedFcn = createCallbackFcn(app, @generatesimulationresultButtonPushed, true);
            app.generatesimulationresultButton.Text = 'generate simulation result';
            app.saveobstaclesButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.saveobstaclesButton.ButtonPushedFcn = createCallbackFcn(app, @saveobstaclesButtonPushed, true);
            app.saveobstaclesButton.Text = 'save obstacles';
            app.loadobstaclesButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.loadobstaclesButton.ButtonPushedFcn = createCallbackFcn(app, @loadobstaclesButtonPushed, true);
            app.loadobstaclesButton.Text = 'load obstacles';
            app.saveresultButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.saveresultButton.ButtonPushedFcn = createCallbackFcn(app, @saveresultButtonPushed, true);
            app.saveresultButton.Text = 'save result';
            app.loadresultButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.loadresultButton.ButtonPushedFcn = createCallbackFcn(app, @loadresultButtonPushed, true);
            app.loadresultButton.Text = 'load result';
            app.resultnameEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'text');
            app.resultnameEditField.Value = 'result1_static1_1';
            app.resultnameEditField_2 = uieditfield(app.obstacleavoidancesimulationPanel, 'text');
            app.resultnameEditField_2.Value = 'testObs1_static1_1';
            app.resultnameEditField_3 = uieditfield(app.obstacleavoidancesimulationPanel, 'text');
            app.resultnameEditField_4 = uieditfield(app.obstacleavoidancesimulationPanel, 'text');
            app.playtheresultButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.playtheresultButton.ButtonPushedFcn = createCallbackFcn(app, @playtheresultButtonPushed, true);
            app.playtheresultButton.Text = 'play the result';
            app.saveorloadobstaclesLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.saveorloadobstaclesLabel.Text = 'save or load obstacles';
            app.managesimulationresultsLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.managesimulationresultsLabel.Text = 'manage simulation results';
            app.PlotForceDistributionGraphButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.PlotForceDistributionGraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotForceDistributionGraphButtonPushed, true);
            app.PlotForceDistributionGraphButton.Text = {'Plot Force-'; 'Distribution Graph'};
            app.SpeedOfObstacleEditFieldLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.SpeedOfObstacleEditFieldLabel.Text = 'Speed Of Obstacle';
            app.SpeedOfObstacleEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.SpeedOfObstacleEditField.Limits = [0 Inf];
            app.SpeedOfObstacleEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedOfObstacleEditFieldValueChanged, true);
            app.msLabel_3 = uilabel(app.obstacleavoidancesimulationPanel);
            app.msLabel_3.Text = 'm/s';
            app.AlgorithmDropDownLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.obstacleavoidancesimulationPanel);
            app.AlgorithmDropDown.Items = {'Optimization Criterion:Even  Force Distribution', 'Optimization Criterion: Stiffness In All Direction', 'Optimization Criterion: Minimum Anchor Displacement'};
            app.AlgorithmDropDown.ItemsData = {'1', '2', '3'};
            app.AlgorithmDropDown.Value = '1';
            app.PlotEEStiffnessGraphButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.PlotEEStiffnessGraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEEStiffnessGraphButtonPushed, true);
            app.PlotEEStiffnessGraphButton.Text = {'Plot EE-'; 'Stiffness Graph'};
            app.ms2Label_3 = uilabel(app.obstacleavoidancesimulationPanel);
            app.ms2Label_3.Text = 'm/(s^2)';
            app.AccelerationOfObstacleEditFieldLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.AccelerationOfObstacleEditFieldLabel.Text = 'Acceleration Of Obstacle';
            app.AccelerationOfObstacleEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.AccelerationOfObstacleEditField.Limits = [0 Inf];
            app.AccelerationOfObstacleEditField.ValueChangedFcn = createCallbackFcn(app, @AccelerationOfObstacleEditFieldValueChanged, true);
            app.AccelerationOfObstacleEditField.Value = 0.8;
            app.ObstacleIDDropDownLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.ObstacleIDDropDownLabel.Text = 'Obstacle ID';
            app.ObstacleIDDropDown = uidropdown(app.obstacleavoidancesimulationPanel);
            app.ObstacleIDDropDown.Items = {'Add New ObsBB'};
            app.ObstacleIDDropDown.ValueChangedFcn = createCallbackFcn(app, @ObstacleIDDropDownValueChanged, true);
            app.ObstacleIDDropDown.Value = 'Add New ObsBB';
            app.YmmSpinnerLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.YmmSpinnerLabel.Text = 'Y/mm';
            app.YmmSpinner = uispinner(app.obstacleavoidancesimulationPanel);
            app.YmmSpinner.Step = 10;
            app.YmmSpinner.ValueChangedFcn = createCallbackFcn(app, @YmmSpinnerValueChanged, true);
            app.ZmmSpinnerLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.ZmmSpinnerLabel.Text = 'Z/mm';
            app.ZmmSpinner = uispinner(app.obstacleavoidancesimulationPanel);
            app.ZmmSpinner.Step = 10;
            app.ZmmSpinner.ValueChangedFcn = createCallbackFcn(app, @ZmmSpinnerValueChanged, true);
            app.ZmmSpinner.Value = 300;
            app.RuntimeOfObstacleEditFieldLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.RuntimeOfObstacleEditFieldLabel.Text = 'Runtime Of Obstacle';
            app.RuntimeOfObstacleEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.RuntimeOfObstacleEditField.Limits = [0 Inf];
            app.RuntimeOfObstacleEditField.Value = 5;
            app.sLabel_4 = uilabel(app.obstacleavoidancesimulationPanel);
            app.sLabel_4.Text = 's';
            app.XmmSpinnerLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.XmmSpinnerLabel.Text = 'X/mm';
            app.XmmSpinner = uispinner(app.obstacleavoidancesimulationPanel);
            app.XmmSpinner.Step = 10;
            app.XmmSpinner.ValueChangedFcn = createCallbackFcn(app, @XmmSpinnerValueChanged, true);
            app.DirectionAngle1SpinnerLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.DirectionAngle1SpinnerLabel.Text = {'Direction'; 'Angle 1'};
            app.DirectionAngle1Spinner = uispinner(app.obstacleavoidancesimulationPanel);
            app.DirectionAngle1Spinner.Step = 10;
            app.DirectionAngle1Spinner.ValueChangedFcn = createCallbackFcn(app, @DirectionAngle1SpinnerValueChanged, true);
            app.DirectionAngle1Spinner.Value = 90;
            app.DirectionAngle2SpinnerLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.DirectionAngle2SpinnerLabel.Text = {'Direction'; 'Angle 2'};
            app.DirectionAngle2Spinner = uispinner(app.obstacleavoidancesimulationPanel);
            app.DirectionAngle2Spinner.Step = 10;
            app.DirectionAngle2Spinner.ValueChangedFcn = createCallbackFcn(app, @DirectionAngle2SpinnerValueChanged, true);
            app.AddButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.Label_7 = uilabel(app.obstacleavoidancesimulationPanel);
            app.Label_7.Text = '°';
            app.Label_8 = uilabel(app.obstacleavoidancesimulationPanel);
            app.Label_8.Text = '°';
            app.minDisToCableButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.minDisToCableButton.ButtonPushedFcn = createCallbackFcn(app, @minDisToCableButtonPushed, true);
            app.minDisToCableButton.Enable = 'off';
            app.minDisToCableButton.Visible = 'off';
            app.minDisToCableButton.Text = {'minDis To'; 'Cable'};
            app.sLabel_5 = uilabel(app.obstacleavoidancesimulationPanel);
            app.sLabel_5.Text = 's';
            app.PlanningtimestepLabel = uilabel(app.obstacleavoidancesimulationPanel);
            app.PlanningtimestepLabel.Text = {'Planning'; ' time step  '};
            app.PlanningtimestepEditField = uieditfield(app.obstacleavoidancesimulationPanel, 'numeric');
            app.PlanningtimestepEditField.ValueChangedFcn = createCallbackFcn(app, @PlanningtimestepEditFieldValueChanged, true);
            app.PlanningtimestepEditField.Value = 0.1;
            app.PlotAnchorTrajectoryGraphButton = uibutton(app.obstacleavoidancesimulationPanel, 'push');
            app.PlotAnchorTrajectoryGraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAnchorTrajectoryGraphButtonPushed, true);
            app.PlotAnchorTrajectoryGraphButton.Text = 'Plot Anchor Trajectory Graph';
            app.CANChannelCDPRopenloopcontrolPanel = uipanel(app.RightPanel);
            app.CANChannelCDPRopenloopcontrolPanel.Title = 'CAN Channel -- CDPR open-loop control';
            app.ConnectandStartCANLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.ConnectandStartCANLabel.Text = 'Connect and Start CAN';
            app.ConnectCANButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.ConnectCANButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCANButtonPushed, true);
            app.ConnectCANButton.Text = 'Connect CAN';
            app.StartCANButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.StartCANButton.ButtonPushedFcn = createCallbackFcn(app, @StartCANButtonPushed, true);
            app.StartCANButton.Text = 'Start CAN';
            app.ResetCANButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.ResetCANButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCANButtonPushed, true);
            app.ResetCANButton.Text = 'Reset CAN';
            app.CANIndexDropDownLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.CANIndexDropDownLabel.Text = 'CAN Index';
            app.CANIndexDropDown = uidropdown(app.CANChannelCDPRopenloopcontrolPanel);
            app.CANIndexDropDown.Items = {'CAN 1', 'CAN 2', ''};
            app.CANIndexDropDown.ItemsData = {'0', '1'};
            app.CANIndexDropDown.ValueChangedFcn = createCallbackFcn(app, @CANIndexDropDownValueChanged, true);
            app.CANIndexDropDown.Value = '0';
            app.BaudRateDropDownLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.CANChannelCDPRopenloopcontrolPanel);
            app.BaudRateDropDown.Items = {'1000', '800', '500', '250', '125', '100', '50', '20', '10', '5'};
            app.BaudRateDropDown.ItemsData = {'1000', '800', '500', '250', '125', '100', '50', '20', '10', '5'};
            app.BaudRateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudRateDropDownValueChanged, true);
            app.BaudRateDropDown.Value = '1000';
            app.KbpsLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.KbpsLabel.Text = 'Kbps';
            app.SingleMotorTestspeedpositionLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.SingleMotorTestspeedpositionLabel.Text = {'Single Motor Test'; '(speed&position)'};
            app.rLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.rLabel.Text = 'r';
            app.rpmLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.rpmLabel.Text = 'rpm';
            app.TestButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.IndexEditFieldLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.IndexEditFieldLabel.Text = 'Index';
            app.IndexEditField = uieditfield(app.CANChannelCDPRopenloopcontrolPanel, 'numeric');
            app.IndexEditField.Limits = [1 8];
            app.IndexEditField.RoundFractionalValues = 'on';
            app.IndexEditField.Value = 1;
            app.RoundEditFieldLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.RoundEditFieldLabel.Text = 'Round';
            app.RoundEditField = uieditfield(app.CANChannelCDPRopenloopcontrolPanel, 'numeric');
            app.RoundEditField.ValueChangedFcn = createCallbackFcn(app, @RoundEditFieldValueChanged, true);
            app.RoundEditField.Value = 3;
            app.SpeedEditFieldLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.CANChannelCDPRopenloopcontrolPanel, 'numeric');
            app.SpeedEditField.Value = 30;
            app.ResetDriverButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.ResetDriverButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDriverButtonPushed, true);
            app.ResetDriverButton.Text = 'Reset Driver';
            app.SetModeDropDownLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.SetModeDropDownLabel.Text = 'Set Mode';
            app.SetModeDropDown = uidropdown(app.CANChannelCDPRopenloopcontrolPanel);
            app.SetModeDropDown.Items = {'position', 'speed&position'};
            app.SetModeDropDown.ItemsData = {'0', '1'};
            app.SetModeDropDown.Value = '1';
            app.SetModeButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.SetModeButton.ButtonPushedFcn = createCallbackFcn(app, @SetModeButtonPushed, true);
            app.SetModeButton.Text = 'Set Mode';
            app.MovableAnchorTest5orderlinearplanningLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.MovableAnchorTest5orderlinearplanningLabel.Text = {'Movable Anchor Test'; '(5-order linear planning)'};
            app.AnchorIDEditFieldLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.AnchorIDEditFieldLabel.Text = 'Anchor ID';
            app.AnchorIDEditField = uieditfield(app.CANChannelCDPRopenloopcontrolPanel, 'numeric');
            app.AnchorIDEditField.Limits = [1 8];
            app.AnchorIDEditField.RoundFractionalValues = 'on';
            app.AnchorIDEditField.Value = 1;
            app.SetDisplacementEditFieldLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.SetDisplacementEditFieldLabel.Text = ' Set Displacement';
            app.SetDisplacementEditField = uieditfield(app.CANChannelCDPRopenloopcontrolPanel, 'numeric');
            app.SetDisplacementEditField.Value = 0.03;
            app.mLabel_2 = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.mLabel_2.Text = 'm';
            app.MaxSpeedEditFieldLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.MaxSpeedEditFieldLabel.Text = 'Max Speed';
            app.MaxSpeedEditField = uieditfield(app.CANChannelCDPRopenloopcontrolPanel, 'numeric');
            app.MaxSpeedEditField.Value = 0.02;
            app.msLabel_2 = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.msLabel_2.Text = 'm/s';
            app.TestButton_2 = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.TestButton_2.ButtonPushedFcn = createCallbackFcn(app, @TestButton_2Pushed, true);
            app.TestButton_2.Text = 'Test';
            app.ms2Label_2 = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.ms2Label_2.Text = 'm/(s^2)';
            app.MaxAccelEditFieldLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.MaxAccelEditFieldLabel.Text = 'Max Accel';
            app.MaxAccelEditField = uieditfield(app.CANChannelCDPRopenloopcontrolPanel, 'numeric');
            app.MaxAccelEditField.Value = 0.01;
            app.SimulationResultTestLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.SimulationResultTestLabel.Text = 'Simulation Result Test';
            app.GoToStartPosespecifiedButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.GoToStartPosespecifiedButton.ButtonPushedFcn = createCallbackFcn(app, @GoToStartPosespecifiedButtonPushed, true);
            app.GoToStartPosespecifiedButton.Text = {'Go To '; 'Start Pose '; 'specified'};
            app.StartTestingSimulationResultButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.StartTestingSimulationResultButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestingSimulationResultButtonPushed, true);
            app.StartTestingSimulationResultButton.Text = {'Start Testing'; 'Simulation Result'};
            app.GoBackToOriginalPoseButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.GoBackToOriginalPoseButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackToOriginalPoseButtonPushed, true);
            app.GoBackToOriginalPoseButton.Text = {'Go Back '; 'To'; 'Original Pose'};
            app.ResetModuleButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.ResetModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ResetModuleButtonPushed, true);
            app.ResetModuleButton.Enable = 'off';
            app.ResetModuleButton.Text = 'Reset Module';
            app.MotorTypeDropDownLabel = uilabel(app.CANChannelCDPRopenloopcontrolPanel);
            app.MotorTypeDropDownLabel.Text = 'Motor Type';
            app.MotorTypeDropDown = uidropdown(app.CANChannelCDPRopenloopcontrolPanel);
            app.MotorTypeDropDown.Items = {'Winch', 'Rail'};
            app.MotorTypeDropDown.ItemsData = {'0', '1'};
            app.MotorTypeDropDown.Value = '0';
            app.ResetInitAllModulesButton = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.ResetInitAllModulesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetInitAllModulesButtonPushed, true);
            app.ResetInitAllModulesButton.Text = {'Reset '; '& Init '; 'All Modules'};
            app.ResetPositionButton_2 = uibutton(app.CANChannelCDPRopenloopcontrolPanel, 'push');
            app.ResetPositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetPositionButton_2Pushed, true);
            app.ResetPositionButton_2.Text = 'Reset Position';
            app.UR5ARMControlForobstacleavoidancetestPanel = uipanel(app.RightPanel);
            app.UR5ARMControlForobstacleavoidancetestPanel.Title = 'UR5-ARM Control (For obstacle avoidance test)';
            app.URIPAdressEditFieldLabel = uilabel(app.UR5ARMControlForobstacleavoidancetestPanel);
            app.URIPAdressEditFieldLabel.Text = 'UR IP Adress';
            app.URIPAdressEditField = uieditfield(app.UR5ARMControlForobstacleavoidancetestPanel, 'text');
            app.URIPAdressEditField.Value = '192.168.1.10';
            app.PortEditFieldLabel = uilabel(app.UR5ARMControlForobstacleavoidancetestPanel);
            app.PortEditFieldLabel.Enable = 'off';
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UR5ARMControlForobstacleavoidancetestPanel, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Enable = 'off';
            app.PortEditField.Value = 30003;
            app.ConnectUR5Button = uibutton(app.UR5ARMControlForobstacleavoidancetestPanel, 'push');
            app.ConnectUR5Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectUR5ButtonPushed, true);
            app.ConnectUR5Button.Text = 'Connect UR5';
            app.TestObstacleTrajectoryButton = uibutton(app.UR5ARMControlForobstacleavoidancetestPanel, 'push');
            app.TestObstacleTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @TestObstacleTrajectoryButtonPushed, true);
            app.TestObstacleTrajectoryButton.Text = 'Test Obstacle Trajectory';
            app.ResetObstaclePositionButton = uibutton(app.UR5ARMControlForobstacleavoidancetestPanel, 'push');
            app.ResetObstaclePositionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetObstaclePositionButtonPushed, true);
            app.ResetObstaclePositionButton.Text = 'Reset Obstacle Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modular_CDPR_TK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end